classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        GoButton                matlab.ui.control.Button
        YearEditField_3Label    matlab.ui.control.Label
        YearEditField_3         matlab.ui.control.EditField
        MonthEditField_3Label   matlab.ui.control.Label
        MonthEditField_3        matlab.ui.control.EditField
        DayEditField_3Label     matlab.ui.control.Label
        DayEditField_3          matlab.ui.control.EditField
        HourEditField_3Label    matlab.ui.control.Label
        HourEditField_3         matlab.ui.control.EditField
        MinuteEditField_3Label  matlab.ui.control.Label
        MinuteEditField_3       matlab.ui.control.EditField
        SecondEditField_3Label  matlab.ui.control.Label
        SecondEditField_3       matlab.ui.control.EditField
        YearEditField_4Label    matlab.ui.control.Label
        YearEditField_4         matlab.ui.control.EditField
        MonthEditField_4Label   matlab.ui.control.Label
        MonthEditField_4        matlab.ui.control.EditField
        DayEditField_4Label     matlab.ui.control.Label
        DayEditField_4          matlab.ui.control.EditField
        HourEditField_4Label    matlab.ui.control.Label
        HourEditField_4         matlab.ui.control.EditField
        MinuteEditField_4Label  matlab.ui.control.Label
        MinuteEditField_4       matlab.ui.control.EditField
        SecondEditField_4Label  matlab.ui.control.Label
        SecondEditField_4       matlab.ui.control.EditField
        FromDateLabel           matlab.ui.control.Label
        ToDateLabel             matlab.ui.control.Label
        SensorFamilyLabel       matlab.ui.control.Label
        SensorFamilyListBox     matlab.ui.control.ListBox
        WeatherUnit             matlab.ui.control.ListBox
        PeopleUnit              matlab.ui.control.ListBox
        UnitLabel               matlab.ui.control.Label
        CoGenChiller            matlab.ui.control.ListBox
        SensorLabel             matlab.ui.control.Label
        CoGenUnit               matlab.ui.control.ListBox
        DomHotWater             matlab.ui.control.ListBox
        SolTherColl             matlab.ui.control.ListBox
        NemoUnit                matlab.ui.control.ListBox
        NemoSensors             matlab.ui.control.ListBox
        MeanTextAreaLabel       matlab.ui.control.Label
        MeanTextArea            matlab.ui.control.TextArea
        STDTextAreaLabel        matlab.ui.control.Label
        STDTextArea             matlab.ui.control.TextArea
        MininumTextAreaLabel    matlab.ui.control.Label
        MininumTextArea         matlab.ui.control.TextArea
        MaximumTextAreaLabel    matlab.ui.control.Label
        MaximumTextArea         matlab.ui.control.TextArea
        Panel                   matlab.ui.container.Panel
        ClearButton             matlab.ui.control.Button
    end


    methods (Access = private)

        % Value changed function: CoGenUnit
        function CoGenUnitValueChanged(app, event)
            switch app.CoGenUnit.Value
                case 'Chiller'
                    app.CoGenChiller.Visible = 'on';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                case 'Domestic Hot Water'
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'on';
                    app.SolTherColl.Visible = 'off';
                case 'Solar Thermal Collectors'
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'on';
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % Once button pushed, format the from date entered for the API.
            rFromDate = strcat(app.YearEditField_3.Value,'-',app.MonthEditField_3.Value, ...
                '-',app.DayEditField_3.Value,'T',app.HourEditField_3.Value,'%3A', ...
                app.MinuteEditField_3.Value,'%3A',app.SecondEditField_3.Value);
            % Once button pushed, format the to date entered for the API.
            rToDate = strcat(app.YearEditField_4.Value,'-',app.MonthEditField_4.Value, ...
                '-',app.DayEditField_4.Value,'T',app.HourEditField_4.Value,'%3A', ...
                app.MinuteEditField_4.Value,'%3A',app.SecondEditField_4.Value);
            % Format the sensor family that was chosen from the list box.
            switch app.SensorFamilyListBox.Value
                case 'People Counter'
                    rFamily = 'people';
                case 'Weather Station'
                    rFamily = 'weather';
                case 'Co-Gen Renewables'
                    rFamily = 'cogen';
                case 'Microgrid Nemo'
                    rFamily = 'nemo';
            end
            rSubSensor = [];
            % Sub sensor initalised in case it is chosen.
            % Format the people counter sensor family, if it was chosen.
            if (strcmp(rFamily,'people'))
                switch app.PeopleUnit.Value
                    case 'PC00.05 (In)'
                        rSensor = '+PC00.05+%28In%29';
                    case 'PC00.05 (Out)'
                        rSensor = '+PC00.05+%28Out%29';
                    case 'PC00.06 (In)'
                        rSensor = '+PC00.06+%28In%29';
                    case 'PC00.06 (Out)'
                        rSensor = '+PC00.06+%28Out%29';
                    case 'PC00.07 (In)'
                        rSensor = '+PC00.07+%28In%29';
                    case 'PC00.07 (Out)'
                        rSensor = '+PC00.07+%28Out%29';
                    case 'PC00.08 (In)'
                        rSensor = '+PC00.08+%28In%29';
                    case 'PC00.08 (Out)'
                        rSensor = '+PC00.08+%28Out%29';
                    case 'PC00.09 (In)'
                        rSensor = '+PC00.09+%28In%29';
                    case 'PC00.09 (Out)'
                        rSensor = '+PC00.09+%28Out%29';
                    case 'PC01.11 (In)'
                        rSensor = '+PC01.11+%28In%29';
                    case 'PC01.11 (Out)'
                        rSensor = '+PC01.11+%28Out%29';
                    case 'PC01.12 (In)'
                        rSensor = '+PC01.12+%28In%29';
                    case 'PC01.12 (Out)'
                        rSensor = '+PC01.12+%28Out%29';
                    case 'PC01.13 (In)'
                        rSensor = '+PC01.13+%28In%29';
                    case 'PC01.13 (Out)'
                        rSensor = '+PC01.13+%28Out%29';
                    case 'PC02.14 (In)'
                        rSensor = '+PC02.14+%28In%29';
                    case 'PC02.14 (Out)'
                        rSensor = '+PC02.14+%28Out%29';
                    case 'PC02.15 (In)'
                        rSensor = '+PC02.15+%28In%29';
                    case 'PC02.15 (Out)'
                        rSensor = '+PC02.15+%28Out%29';
                    case 'PC02.16 (In)'
                        rSensor = '+PC02.16+%28In%29';
                    case 'PC02.16 (Out)'
                        rSensor = '+PC02.16+%28Out%29';
                    case 'PC03.17 (In)'
                        rSensor = '+PC03.17+%28In%29';
                    case 'PC03.17 (Out)'
                        rSensor = '+PC03.17+%28Out%29';
                    case 'PC04.20 (In)'
                        rSensor = '+PC04.20+%28In%29';
                    case 'PC04.20 (Out)'
                        rSensor = '+PC04.20+%28Out%29';
                    case 'PC05.21 (In)'
                        rSensor = '+PC05.21+%28In%29';
                    case 'PC05.21 (Out)'
                        rSensor = '+PC05.21+%28Out%29';
                    case 'PC05.22 (In)'
                        rSensor = '+PC05.22+%28In%29';
                    case 'PC05.22 (Out)'
                        rSensor = '+PC05.22+%28Out%29';
                    case 'PC05.23 (In)'
                        rSensor = '+PC05.23+%28In%29';
                    case 'PC05.23 (Out)'
                        rSensor = '+PC05.23+%28Out%29';
                    case 'PC05.24 (In)'
                        rSensor = '+PC05.24+%28In%29';
                    case 'PC05.24 (Out)'
                        rSensor = '+PC05.24+%28Out%29';
                    case 'PC08.25 (In)'
                        rSensor = '+PC08.25+%28In%29';
                    case 'PC08.25 (Out)'
                        rSensor = '+PC08.25+%28Out%29';
                    case 'PC09.26 (In)'
                        rSensor = '+PC09.26+%28In%29';
                    case 'PC09.26 (Out)'
                        rSensor = '+PC09.26+%28Out%29';
                    case 'PC09.27 (In)'
                        rSensor = '+PC09.27+%28In%29';
                    case 'PC09.27 (Out)'
                        rSensor = '+PC09.27+%28Out%29';
                    case 'PC09.28 (In)'
                        rSensor = '+PC09.28+%28In%29';
                    case 'PC09.28 (Out)'
                        rSensor = '+PC09.28+%28Out%29';
                    case 'PC09.29 (In)'
                        rSensor = '+PC09.29+%28In%29';
                    case 'PC09.29 (Out)'
                        rSensor = '+PC09.29+%28Out%29';
                    case 'PC10.30 (In)'
                        rSensor = '+PC10.30+%28In%29';
                    case 'PC10.30 (Out)'
                        rSensor = '+PC10.30+%28Out%29';
                    case 'PC11.31 (In)'
                        rSensor = '+PC11.31+%28In%29';
                    case 'PC11.31 (Out)'
                        rSensor = '+PC11.31+%28Out%29';
                    case 'PC11.32 (In)'
                        rSensor = '+PC11.32+%28In%29';
                    case 'PC11.32 (Out)'
                        rSensor = '+PC11.32+%28Out%29';
                    case 'PC11.33 (In)'
                        rSensor = '+PC11.33+%28In%29';
                    case 'PC11.33 (Out)'
                        rSensor = '+PC11.33+%28Out%29';
                    case 'PCB1.01 (In)'
                        rSensor = '+PCB1.01+%28In%29';
                    case 'PCB1.01 (Out)'
                        rSensor = '+PCB1.01+%28Out%29';
                    case 'PCB1.02 (In)'
                        rSensor = '+PCB1.02+%28In%29';
                    case 'PCB1.02 (Out)'
                        rSensor = '+PCB1.02+%28Out%29';
                end
            end
            % Format the weather station sensor family, if it was chosen.
            if (strcmp(rFamily,'weather'))
                switch app.WeatherUnit.Value
                    case 'Wind Direction'
                        rSensor = 'WD';
                    case 'Air Temperature'
                        rSensor = 'AT';
                    case 'Solar Radiation'
                        rSensor = 'SR';
                    case 'Rain Gauge'
                        rSensor = 'RG';
                    case 'Solar Voltage'
                        rSensor = 'SV';
                    case 'Peak Wind Gust'
                        rSensor = 'PW';
                    case 'UVA Radiation'
                        rSensor = 'UV';
                    case 'WDIR Cosine'
                        rSensor = 'WC';
                    case 'WDIR Sine'
                        rSensor = 'WS';
                    case 'Battery Voltage'
                        rSensor = 'BV';
                    case 'Load Current'
                        rSensor = 'LC';
                    case 'Charge Current'
                        rSensor = 'CC';
                    case 'Inst. Wind Speed'
                        rSensor = 'IWS';
                    case 'Inst. Wind Direction'
                        rSensor = 'IWD';
                    case 'N/-S Wind Sp (VCos)'
                        rSensor = 'NW';
                    case 'E/-W Wind Sp (VSin)'
                        rSensor = 'EW';
                    case 'Rain Total from 9am'
                        rSensor = 'RT';
                    case 'Communications'
                        rSensor = 'Co';
                end
            end
            
            if (strcmp(rFamily,'cogen'))
                switch app.CoGenUnit.Value
                    case 'Chiller'
                        rSensor = 'Chiller';
                        if (strcmp(rSensor,'Chiller'))
                            switch app.CoGenChiller.Value
                                case 'Chiller Instant Power'
                                    rSubSensor = '1';
                                case 'Chiller Lifetime Energy'
                                    rSubSensor = '2';
                                case 'CH Instant Power Rejected'
                                    rSubSensor = '5';
                                case 'CH Lifetime Energy Rejected'
                                    rSubSensor = '6';
                            end
                        end
                    case 'Domestic Hot Water'
                        rSensor = 'Domestic+Hot+Water';
                        if (strcmp(rSensor,'Domestic+Hot+Water'))
                            switch app.DomHotWater.Value
                                case 'DHW Instant Power'
                                    rSubSensor = '3';
                                case 'DHW Lifetime Energy'
                                    rSubSensor = '4';
                            end
                        end
                        
                    case 'Solar Thermal Collectors'
                        rSensor = 'Solar+Thermal+Collectors';
                        if (strcmp(rSensor,'Solar+Thermal+Collectors'))
                            switch app.SolTherColl.Value
                                case 'STC Instant Power'
                                    rSubSensor = '7';
                                case 'STC Lifetime Energy'
                                    rSubSensor = '8';
                            end
                        end
                end
            end
            
            if (strcmp(rFamily,'nemo'))
                switch app.NemoUnit.Value
                    case 'MDB EIF Output'
                        rSensor = 'MDB+EIF+Output';
                        if (strcmp(rSensor, 'MDB+EIF+Output'))
                            switch app.NemoSensors.Value
                                case 'Phase 1 Voltage'
                                    rSubSensor = '4096';
                                case 'Phase 2 Voltage'
                                    rSubSensor = '4098';
                                case 'Phase 3 Voltage'
                                    rSubSensor = '4100';
                                case 'Neutral Current'
                                    rSubSensor = '4108';
                                case '3-phase Active Power'
                                    rSubSensor = '4116';
                                case '3-phase Reactive Power'
                                    rSubSensor = '4118';
                                case '3-phase Apparent Power'
                                    rSubSensor = '4120';
                                case '3-Phase Positive Active Energy'
                                    rSubSensor = '4124';
                                case '3-Phase Positive Reactive Energy'
                                    rSubSensor = '4126';
                                case '3-Phase Negative Active Energy'
                                    rSubSensor = '4128';
                                case '3-Phase Negative Reactive Energy'
                                    rSubSensor = '4130';
                                case '3-phase Power Factor'
                                    rSubSensor = '4132';
                                case 'Frequency'
                                    rSubSensor = '4134';
                                case '3-phase Average Power'
                                    rSubSensor = '4135';
                                case '3-phase Peak Maximum Demand'
                                    rSubSensor = '4137';
                                    
                            end
                        end
                end
            end
            
            % Format the url for the EIF API system.
            url = 'http://eif-research.feit.uts.edu.au/api/dl/';
            url_full = strcat(url,'?rFromDate=',rFromDate, ...
                '&rToDate=',rToDate, ...
                '&rFamily=',rFamily, ...
                '&rSensor=',rSensor, ...
                '&rSubSensor=', rSubSensor);
            % Save the data from the EIF API system by downloading and saving
            % the .csv file locally, then plotting to screen.
            
            websave('local3.csv',url_full);
            M = csvread('local3.csv',0,1);
            mean_x =  mean(M);
            std_x = std(M);
            msgbox(sprintf('%d' , mean_x));
            plot(app.UIAxes,M)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, ones(length(M),1)*mean_x, 'r')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, ones(length(M),1)*std_x, 'g')
            

        end

        % Value changed function: SensorFamilyListBox
        function SensorFamilyListBoxValueChanged(app, event)
            % Format the UI to hide irrelevant unit and sensor list boxed.
            switch app.SensorFamilyListBox.Value
                % For example, if People Counter is chosen, then turn on the list
                % box with its corresponding units and sensors. Others are turned off.
                case 'People Counter'
                    app.PeopleUnit.Visible = 'on';
                    app.WeatherUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.CoGenUnit.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                case 'Weather Station'
                    app.WeatherUnit.Visible = 'on';
                    app.PeopleUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.CoGenUnit.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                case 'Co-Gen Renewables'
                    app.CoGenUnit.Visible = 'on';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                case 'Microgrid Nemo'
                    app.CoGenUnit.Visible = 'off';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'on';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'on';
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            hold(app.UIAxes, 'off')
            plot(app.UIAxes,zeros(0))
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Units')
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.YearEditField_3Label = uilabel(app.UIFigure);
            app.YearEditField_3Label.Text = 'Year';
            app.YearEditField_3 = uieditfield(app.UIFigure, 'text');
            app.MonthEditField_3Label = uilabel(app.UIFigure);
            app.MonthEditField_3Label.Text = 'Month';
            app.MonthEditField_3 = uieditfield(app.UIFigure, 'text');
            app.DayEditField_3Label = uilabel(app.UIFigure);
            app.DayEditField_3Label.Text = 'Day';
            app.DayEditField_3 = uieditfield(app.UIFigure, 'text');
            app.HourEditField_3Label = uilabel(app.UIFigure);
            app.HourEditField_3Label.Text = 'Hour';
            app.HourEditField_3 = uieditfield(app.UIFigure, 'text');
            app.MinuteEditField_3Label = uilabel(app.UIFigure);
            app.MinuteEditField_3Label.Text = 'Minute';
            app.MinuteEditField_3 = uieditfield(app.UIFigure, 'text');
            app.SecondEditField_3Label = uilabel(app.UIFigure);
            app.SecondEditField_3Label.Text = 'Second';
            app.SecondEditField_3 = uieditfield(app.UIFigure, 'text');
            app.YearEditField_4Label = uilabel(app.UIFigure);
            app.YearEditField_4Label.Text = 'Year';
            app.YearEditField_4 = uieditfield(app.UIFigure, 'text');
            app.MonthEditField_4Label = uilabel(app.UIFigure);
            app.MonthEditField_4Label.Text = 'Month';
            app.MonthEditField_4 = uieditfield(app.UIFigure, 'text');
            app.DayEditField_4Label = uilabel(app.UIFigure);
            app.DayEditField_4Label.Text = 'Day';
            app.DayEditField_4 = uieditfield(app.UIFigure, 'text');
            app.HourEditField_4Label = uilabel(app.UIFigure);
            app.HourEditField_4Label.Text = 'Hour';
            app.HourEditField_4 = uieditfield(app.UIFigure, 'text');
            app.MinuteEditField_4Label = uilabel(app.UIFigure);
            app.MinuteEditField_4Label.Text = 'Minute';
            app.MinuteEditField_4 = uieditfield(app.UIFigure, 'text');
            app.SecondEditField_4Label = uilabel(app.UIFigure);
            app.SecondEditField_4Label.Text = 'Second';
            app.SecondEditField_4 = uieditfield(app.UIFigure, 'text');
            app.FromDateLabel = uilabel(app.UIFigure);
            app.FromDateLabel.Text = 'From Date';
            app.ToDateLabel = uilabel(app.UIFigure);
            app.ToDateLabel.Text = 'To Date';
            app.SensorFamilyLabel = uilabel(app.UIFigure);
            app.SensorFamilyLabel.Text = 'Sensor Family';
            app.SensorFamilyListBox = uilistbox(app.UIFigure);
            app.SensorFamilyListBox.Items = {'People Counter', 'Weather Station', 'Co-Gen Renewables', 'Microgrid Nemo'};
            app.SensorFamilyListBox.ValueChangedFcn = createCallbackFcn(app, @SensorFamilyListBoxValueChanged, true);
            app.SensorFamilyListBox.Value = {};
            app.WeatherUnit = uilistbox(app.UIFigure);
            app.WeatherUnit.Items = {'Wind Direction', 'Air Temperature', 'Solar Radiation', 'Rain Gauge', 'Solar Voltage', 'Peak Wind Gust', 'UVA Radiation', 'WDIR Cosine', 'WDIR Sine', 'Battery Voltage', 'Load Current', 'Charge Current', 'Inst. Wind Speed', 'Inst. Wind Direction', 'N/-S Wind Sp (VCos)', 'E/-W Wind Sp (VSin)', 'Rain Total from 9am', 'Communications'};
            app.WeatherUnit.Visible = 'off';
            app.WeatherUnit.Value = {};
            app.PeopleUnit = uilistbox(app.UIFigure);
            app.PeopleUnit.Items = {'PC00.05 (In)', 'PC00.05 (Out)', 'PC00.06 (In)', 'PC00.06 (Out)', 'PC00.07 (In)', 'PC00.07 (Out)', 'PC00.08 (In)', 'PC00.08 (Out)', 'PC00.09 (In)', 'PC00.09 (Out)', 'PC01.11 (In)', 'PC01.11 (Out)', 'PC01.12 (In)', 'PC01.12 (Out)', 'PC01.13 (In)', 'PC01.13 (Out)', 'PC02.14 (In)', 'PC02.14 (Out)', 'PC02.15 (In)', 'PC02.15 (Out)', 'PC02.16 (In)', 'PC02.16 (Out)', 'PC03.17 (In)', 'PC03.17 (Out)', 'PC04.20 (In)', 'PC04.20 (Out)', 'PC05.21 (In)', 'PC05.21 (Out)', 'PC05.22 (In)', 'PC05.22 (Out)', 'PC05.23 (In)', 'PC05.23 (Out)', 'PC05.24 (In)', 'PC05.24 (Out)', 'PC08.25 (In)', 'PC08.25 (Out)', 'PC09.26 (In)', 'PC09.26 (Out)', 'PC09.27 (In)', 'PC09.27 (Out)', 'PC09.28 (In)', 'PC09.28 (Out)', 'PC09.29 (In)', 'PC09.29 (Out)', 'PC10.30 (In)', 'PC10.30 (Out)', 'PC11.31 (In)', 'PC11.31 (Out)', 'PC11.32 (In)', 'PC11.32 (Out)', 'PC11.33 (In)', 'PC11.33 (Out)', 'PCB1.01 (In)', 'PCB1.01 (Out)', 'PCB1.02 (In)', 'PCB1.02 (Out)'};
            app.PeopleUnit.Value = {};
            app.UnitLabel = uilabel(app.UIFigure);
            app.UnitLabel.Text = 'Unit';
            app.CoGenChiller = uilistbox(app.UIFigure);
            app.CoGenChiller.Items = {'Chiller Instant Power', 'Chiller Lifetime Energy', 'CH Instant Power Rejected', 'CH Lifetime Energy Rejected'};
            app.CoGenChiller.Visible = 'off';
            app.CoGenChiller.Value = {};
            app.SensorLabel = uilabel(app.UIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.CoGenUnit = uilistbox(app.UIFigure);
            app.CoGenUnit.Items = {'Chiller', 'Domestic Hot Water', 'Solar Thermal Collectors'};
            app.CoGenUnit.ValueChangedFcn = createCallbackFcn(app, @CoGenUnitValueChanged, true);
            app.CoGenUnit.Visible = 'off';
            app.CoGenUnit.Value = {};
            app.DomHotWater = uilistbox(app.UIFigure);
            app.DomHotWater.Items = {'DHW Instant Power', 'DHW Lifetime Energy'};
            app.DomHotWater.Visible = 'off';
            app.DomHotWater.Value = {};
            app.SolTherColl = uilistbox(app.UIFigure);
            app.SolTherColl.Items = {'STC Instant Power', 'STC Lifetime Energy'};
            app.SolTherColl.Visible = 'off';
            app.SolTherColl.Value = {};
            app.NemoUnit = uilistbox(app.UIFigure);
            app.NemoUnit.Items = {'MDB EIF Output'};
            app.NemoUnit.Visible = 'off';
            app.NemoUnit.Value = {};
            app.NemoSensors = uilistbox(app.UIFigure);
            app.NemoSensors.Items = {'Phase 1 Voltage', 'Phase 2 Voltage', 'Phase 3 Voltage', 'Neutral Current', '3-phase Active Power', '3-phase Reactive Power', '3-phase Apparent Power', '3-Phase Positive Active Energy', '3-Phase Positive Reactive Energy', '3-Phase Negative Active Energy', '3-Phase Negative Reactive Energy', '3-phase Power Factor', 'Frequency', '3-phase Average Power', '3-phase Peak Maximum Demand', '(I1+I2+I3)/3'};
            app.NemoSensors.Visible = 'off';
            app.NemoSensors.Value = {};
            app.MeanTextAreaLabel = uilabel(app.UIFigure);
            app.MeanTextAreaLabel.Text = 'Mean';
            app.MeanTextArea = uitextarea(app.UIFigure);
            app.STDTextAreaLabel = uilabel(app.UIFigure);
            app.STDTextAreaLabel.Text = 'STD';
            app.STDTextArea = uitextarea(app.UIFigure);
            app.MininumTextAreaLabel = uilabel(app.UIFigure);
            app.MininumTextAreaLabel.Text = 'Mininum';
            app.MininumTextArea = uitextarea(app.UIFigure);
            app.MaximumTextAreaLabel = uilabel(app.UIFigure);
            app.MaximumTextAreaLabel.Text = 'Maximum';
            app.MaximumTextArea = uitextarea(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end