classdef sensorsDONE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        GoButton                 matlab.ui.control.Button
        YearEditField_3Label     matlab.ui.control.Label
        YearEditField_3          matlab.ui.control.EditField
        MonthEditField_3Label    matlab.ui.control.Label
        MonthEditField_3         matlab.ui.control.EditField
        DayEditField_3Label      matlab.ui.control.Label
        DayEditField_3           matlab.ui.control.EditField
        HourEditField_3Label     matlab.ui.control.Label
        HourEditField_3          matlab.ui.control.EditField
        MinuteEditField_3Label   matlab.ui.control.Label
        MinuteEditField_3        matlab.ui.control.EditField
        SecondEditField_3Label   matlab.ui.control.Label
        SecondEditField_3        matlab.ui.control.EditField
        YearEditField_4Label     matlab.ui.control.Label
        YearEditField_4          matlab.ui.control.EditField
        MonthEditField_4Label    matlab.ui.control.Label
        MonthEditField_4         matlab.ui.control.EditField
        DayEditField_4Label      matlab.ui.control.Label
        DayEditField_4           matlab.ui.control.EditField
        HourEditField_4Label     matlab.ui.control.Label
        HourEditField_4          matlab.ui.control.EditField
        MinuteEditField_4Label   matlab.ui.control.Label
        MinuteEditField_4        matlab.ui.control.EditField
        SecondEditField_4Label   matlab.ui.control.Label
        SecondEditField_4        matlab.ui.control.EditField
        FromDateLabel            matlab.ui.control.Label
        ToDateLabel              matlab.ui.control.Label
        SensorFamilyLabel        matlab.ui.control.Label
        SensorFamilyListBox      matlab.ui.control.ListBox
        WeatherUnit              matlab.ui.control.ListBox
        PeopleUnit               matlab.ui.control.ListBox
        UnitLabel                matlab.ui.control.Label
        CoGenChiller             matlab.ui.control.ListBox
        SensorLabel              matlab.ui.control.Label
        CoGenUnit                matlab.ui.control.ListBox
        DomHotWater              matlab.ui.control.ListBox
        SolTherColl              matlab.ui.control.ListBox
        NemoUnit                 matlab.ui.control.ListBox
        NemoSensors              matlab.ui.control.ListBox
        ClearButton              matlab.ui.control.Button
        VAWTUnit                 matlab.ui.control.ListBox
        VAWTSensor               matlab.ui.control.ListBox
        WaspSensor               matlab.ui.control.ListBox
        WaspUnit                 matlab.ui.control.ListBox
        PCUnit                   matlab.ui.control.ListBox
        MeanTextAreaLabel        matlab.ui.control.Label
        MeanTextArea             matlab.ui.control.TextArea
        STDTextAreaLabel         matlab.ui.control.Label
        STDTextArea              matlab.ui.control.TextArea
        MininumTextAreaLabel     matlab.ui.control.Label
        MininumTextArea          matlab.ui.control.TextArea
        MaximumTextAreaLabel     matlab.ui.control.Label
        MaximumTextArea          matlab.ui.control.TextArea
        SumTextAreaLabel         matlab.ui.control.Label
        SumTextArea              matlab.ui.control.TextArea
        GoCurrentTime            matlab.ui.control.Button
        YearEditField_5Label_2   matlab.ui.control.Label
        YearAgo                  matlab.ui.control.NumericEditField
        MonthEditField_5Label    matlab.ui.control.Label
        MonthAgo                 matlab.ui.control.NumericEditField
        DayLabel                 matlab.ui.control.Label
        DayAgo                   matlab.ui.control.NumericEditField
        HourEditField_5Label     matlab.ui.control.Label
        HourAgo                  matlab.ui.control.NumericEditField
        MinuteEditField_5Label   matlab.ui.control.Label
        MinuteAgo                matlab.ui.control.NumericEditField
        SecondEditField_5Label   matlab.ui.control.Label
        SecondAgo                matlab.ui.control.NumericEditField
        KeepUpToDateSwitchLabel  matlab.ui.control.Label
        KeepUp                   matlab.ui.control.ToggleSwitch
        ZPointEditFieldLabel     matlab.ui.control.Label
        ZPoint                   matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        ProbBe                   matlab.ui.control.EditField
        EmbedUnit                matlab.ui.control.ListBox
        Embed1Sensor             matlab.ui.control.ListBox
        Embed2Sensor             matlab.ui.control.ListBox
        Embed3Sensor             matlab.ui.control.ListBox
        Embed4Sensor             matlab.ui.control.ListBox
        Embed5Sensor             matlab.ui.control.ListBox
        PlaceholderSensor        matlab.ui.control.ListBox
        TurbineUnit              matlab.ui.control.ListBox
        TurbineSensor            matlab.ui.control.ListBox
    end


    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %Clears the plot
            hold(app.UIAxes, 'off')
            plot(app.UIAxes,zeros(1))
            flag = 0;
        end

        % Value changed function: CoGenUnit
        function CoGenUnitValueChanged(app, event)
            switch app.CoGenUnit.Value
                case 'Chiller'
                    app.CoGenChiller.Visible = 'on';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                case 'Domestic Hot Water'
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'on';
                    app.SolTherColl.Visible = 'off';
                case 'Solar Thermal Collectors'
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'on';
            end
        end

        % Value changed function: EmbedUnit
        function EmbedUnitValueChanged(app, event)
            switch app.EmbedUnit.Value
                case 'ESD12_1'
                    app.Embed1Sensor.Visible = 'on';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                case 'ESD12_2'
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'on';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                case 'ESD13_1'
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'on';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                case 'ESDB4_1'
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'on';
                    app.Embed5Sensor.Visible = 'off';
                case 'ESDB4_2'
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'on';
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % Once button pushed, format the from date entered for the API.
            rFromDate = strcat(app.YearEditField_3.Value,'-',app.MonthEditField_3.Value, ...
                '-',app.DayEditField_3.Value,'T',app.HourEditField_3.Value,'%3A', ...
                app.MinuteEditField_3.Value,'%3A',app.SecondEditField_3.Value);
            % Once button pushed, format the to date entered for the API.
            rToDate = strcat(app.YearEditField_4.Value,'-',app.MonthEditField_4.Value, ...
                '-',app.DayEditField_4.Value,'T',app.HourEditField_4.Value,'%3A', ...
                app.MinuteEditField_4.Value,'%3A',app.SecondEditField_4.Value);
            % Format the sensor family that was chosen from the list box.
            switch app.SensorFamilyListBox.Value
                case 'People Counter'
                    rFamily = 'people';
                case 'Weather Station'
                    rFamily = 'weather';
                case 'Co-Gen Renewables'
                    rFamily = 'cogen';
                case 'Microgrid Nemo'
                    rFamily = 'nemo';
                case 'VAWT Power Output'
                    rFamily = 'crompton';
                case 'Waspmote'
                    rFamily = 'wasp';
                case 'PCLabs Logins'
                    rFamily = 'logins';
                case 'Embedded Sensors'
                    rFamily = 'embed';
                case 'Turbine Effects'
                    rFamily = 'turbine';
            end
            rSubSensor = [];
            % Sub sensor initalised in case it is chosen.
            % Format the people counter sensor family, if it was chosen.
            if (strcmp(rFamily,'people'))
                rSubSensor = [];
                switch app.PeopleUnit.Value
                    case 'PC00.05 (In)'
                        rSensor = '+PC00.05+%28In%29';
                    case 'PC00.05 (Out)'
                        rSensor = '+PC00.05+%28Out%29';
                    case 'PC00.06 (In)'
                        rSensor = '+PC00.06+%28In%29';
                    case 'PC00.06 (Out)'
                        rSensor = '+PC00.06+%28Out%29';
                    case 'PC00.07 (In)'
                        rSensor = '+PC00.07+%28In%29';
                    case 'PC00.07 (Out)'
                        rSensor = '+PC00.07+%28Out%29';
                    case 'PC00.08 (In)'
                        rSensor = '+PC00.08+%28In%29';
                    case 'PC00.08 (Out)'
                        rSensor = '+PC00.08+%28Out%29';
                    case 'PC00.09 (In)'
                        rSensor = '+PC00.09+%28In%29';
                    case 'PC00.09 (Out)'
                        rSensor = '+PC00.09+%28Out%29';
                    case 'PC01.11 (In)'
                        rSensor = '+PC01.11+%28In%29';
                    case 'PC01.11 (Out)'
                        rSensor = '+PC01.11+%28Out%29';
                    case 'PC01.12 (In)'
                        rSensor = '+PC01.12+%28In%29';
                    case 'PC01.12 (Out)'
                        rSensor = '+PC01.12+%28Out%29';
                    case 'PC01.13 (In)'
                        rSensor = '+PC01.13+%28In%29';
                    case 'PC01.13 (Out)'
                        rSensor = '+PC01.13+%28Out%29';
                    case 'PC02.14 (In)'
                        rSensor = '+PC02.14+%28In%29';
                    case 'PC02.14 (Out)'
                        rSensor = '+PC02.14+%28Out%29';
                    case 'PC02.15 (In)'
                        rSensor = '+PC02.15+%28In%29';
                    case 'PC02.15 (Out)'
                        rSensor = '+PC02.15+%28Out%29';
                    case 'PC02.16 (In)'
                        rSensor = '+PC02.16+%28In%29';
                    case 'PC02.16 (Out)'
                        rSensor = '+PC02.16+%28Out%29';
                    case 'PC03.17 (In)'
                        rSensor = '+PC03.17+%28In%29';
                    case 'PC03.17 (Out)'
                        rSensor = '+PC03.17+%28Out%29';
                    case 'PC04.20 (In)'
                        rSensor = '+PC04.20+%28In%29';
                    case 'PC04.20 (Out)'
                        rSensor = '+PC04.20+%28Out%29';
                    case 'PC05.21 (In)'
                        rSensor = '+PC05.21+%28In%29';
                    case 'PC05.21 (Out)'
                        rSensor = '+PC05.21+%28Out%29';
                    case 'PC05.22 (In)'
                        rSensor = '+PC05.22+%28In%29';
                    case 'PC05.22 (Out)'
                        rSensor = '+PC05.22+%28Out%29';
                    case 'PC05.23 (In)'
                        rSensor = '+PC05.23+%28In%29';
                    case 'PC05.23 (Out)'
                        rSensor = '+PC05.23+%28Out%29';
                    case 'PC05.24 (In)'
                        rSensor = '+PC05.24+%28In%29';
                    case 'PC05.24 (Out)'
                        rSensor = '+PC05.24+%28Out%29';
                    case 'PC08.25 (In)'
                        rSensor = '+PC08.25+%28In%29';
                    case 'PC08.25 (Out)'
                        rSensor = '+PC08.25+%28Out%29';
                    case 'PC09.26 (In)'
                        rSensor = '+PC09.26+%28In%29';
                    case 'PC09.26 (Out)'
                        rSensor = '+PC09.26+%28Out%29';
                    case 'PC09.27 (In)'
                        rSensor = '+PC09.27+%28In%29';
                    case 'PC09.27 (Out)'
                        rSensor = '+PC09.27+%28Out%29';
                    case 'PC09.28 (In)'
                        rSensor = '+PC09.28+%28In%29';
                    case 'PC09.28 (Out)'
                        rSensor = '+PC09.28+%28Out%29';
                    case 'PC09.29 (In)'
                        rSensor = '+PC09.29+%28In%29';
                    case 'PC09.29 (Out)'
                        rSensor = '+PC09.29+%28Out%29';
                    case 'PC10.30 (In)'
                        rSensor = '+PC10.30+%28In%29';
                    case 'PC10.30 (Out)'
                        rSensor = '+PC10.30+%28Out%29';
                    case 'PC11.31 (In)'
                        rSensor = '+PC11.31+%28In%29';
                    case 'PC11.31 (Out)'
                        rSensor = '+PC11.31+%28Out%29';
                    case 'PC11.32 (In)'
                        rSensor = '+PC11.32+%28In%29';
                    case 'PC11.32 (Out)'
                        rSensor = '+PC11.32+%28Out%29';
                    case 'PC11.33 (In)'
                        rSensor = '+PC11.33+%28In%29';
                    case 'PC11.33 (Out)'
                        rSensor = '+PC11.33+%28Out%29';
                    case 'PCB1.01 (In)'
                        rSensor = '+PCB1.01+%28In%29';
                    case 'PCB1.01 (Out)'
                        rSensor = '+PCB1.01+%28Out%29';
                    case 'PCB1.02 (In)'
                        rSensor = '+PCB1.02+%28In%29';
                    case 'PCB1.02 (Out)'
                        rSensor = '+PCB1.02+%28Out%29';
                end
            end
            % Format the weather station sensor family, if it was chosen.
            if (strcmp(rFamily,'weather'))
                rSubSensor = [];
                switch app.WeatherUnit.Value
                    case 'Wind Direction'
                        rSensor = 'WD';
                    case 'Air Temperature'
                        rSensor = 'AT';
                    case 'Solar Radiation'
                        rSensor = 'SR';
                    case 'Rain Gauge'
                        rSensor = 'RG';
                    case 'Solar Voltage'
                        rSensor = 'SV';
                    case 'Peak Wind Gust'
                        rSensor = 'PW';
                    case 'UVA Radiation'
                        rSensor = 'UV';
                    case 'WDIR Cosine'
                        rSensor = 'WC';
                    case 'WDIR Sine'
                        rSensor = 'WS';
                    case 'Battery Voltage'
                        rSensor = 'BV';
                    case 'Load Current'
                        rSensor = 'LC';
                    case 'Charge Current'
                        rSensor = 'CC';
                    case 'Inst. Wind Speed'
                        rSensor = 'IWS';
                    case 'Inst. Wind Direction'
                        rSensor = 'IWD';
                    case 'N/-S Wind Sp (VCos)'
                        rSensor = 'NW';
                    case 'E/-W Wind Sp (VSin)'
                        rSensor = 'EW';
                    case 'Rain Total from 9am'
                        rSensor = 'RT';
                    case 'Communications'
                        rSensor = 'Co';
                end
            end
            
            if (strcmp(rFamily,'cogen'))
                switch app.CoGenUnit.Value
                    case 'Chiller'
                        rSensor = 'Chiller';
                        switch app.CoGenChiller.Value
                            case 'Chiller Instant Power'
                                rSubSensor = '1';
                            case 'Chiller Lifetime Energy'
                                rSubSensor = '2';
                            case 'CH Instant Power Rejected'
                                rSubSensor = '5';
                            case 'CH Lifetime Energy Rejected'
                                rSubSensor = '6';
                        end
                    case 'Domestic Hot Water'
                        rSensor = 'Domestic+Hot+Water';
                        switch app.DomHotWater.Value
                            case 'DHW Instant Power'
                                rSubSensor = '3';
                            case 'DHW Lifetime Energy'
                                rSubSensor = '4';
                        end
                    case 'Solar Thermal Collectors'
                        rSensor = 'Solar+Thermal+Collectors';
                        switch app.SolTherColl.Value
                            case 'STC Instant Power'
                                rSubSensor = '7';
                            case 'STC Lifetime Energy'
                                rSubSensor = '8';
                        end
                end
            end
            
            if (strcmp(rFamily,'nemo'))
                switch app.NemoUnit.Value
                    case 'MDB EIF Output'
                        rSensor = 'MDB+EIF+Output';
                        switch app.NemoSensors.Value
                            case 'Phase 1 Voltage'
                                rSubSensor = '4096';
                            case 'Phase 2 Voltage'
                                rSubSensor = '4098';
                            case 'Phase 3 Voltage'
                                rSubSensor = '4100';
                            case 'Neutral Current'
                                rSubSensor = '4108';
                            case '3-phase Active Power'
                                rSubSensor = '4116';
                            case '3-phase Reactive Power'
                                rSubSensor = '4118';
                            case '3-phase Apparent Power'
                                rSubSensor = '4120';
                            case '3-Phase Positive Active Energy'
                                rSubSensor = '4124';
                            case '3-Phase Positive Reactive Energy'
                                rSubSensor = '4126';
                            case '3-Phase Negative Active Energy'
                                rSubSensor = '4128';
                            case '3-Phase Negative Reactive Energy'
                                rSubSensor = '4130';
                            case '3-phase Power Factor'
                                rSubSensor = '4132';
                            case 'Frequency'
                                rSubSensor = '4134';
                            case '3-phase Average Power'
                                rSubSensor = '4135';
                            case '3-phase Peak Maximum Demand'
                                rSubSensor = '4137';
                                
                        end
                end
            end
            if (strcmp(rFamily,'crompton'))
                switch app.VAWTUnit.Value
                    case 'VAWT'
                        rSensor = 'VAWT';
                        switch app.VAWTSensor.Value
                            case 'Phase 1 line to neutral volts'
                                rSubSensor = '1';
                            case 'Phase 2 line to neutral volts'
                                rSubSensor = '3';
                            case 'Phase 3 line to neutral volts'
                                rSubSensor = '5';
                            case 'Phase 1 current'
                                rSubSensor = '7';
                            case 'Phase 2 current'
                                rSubSensor = '9';
                            case 'Phase 3 current'
                                rSubSensor = '11';
                            case 'Phase 1 power'
                                rSubSensor = '13';
                            case 'Phase 2 power'
                                rSubSensor = '15';
                            case 'Phase 3 power'
                                rSubSensor = '17';
                            case 'Phase 1 power factor'
                                rSubSensor = '31';
                            case 'Phase 2 power factor'
                                rSubSensor = '33';
                            case 'Phase 3 power factor'
                                rSubSensor = '35';
                            case 'Sum of line currents'
                                rSubSensor = '49';
                            case 'Total system power'
                                rSubSensor = '53';
                            case 'Frequency of supply voltages'
                                rSubSensor = '71';
                            case 'Average line to line volts'
                                rSubSensor = '207';
                        end
                end
            end
            
            if (strcmp(rFamily,'wasp'))
                switch app.WaspUnit.Value
                    case 'ES_B_11_429_3E90'
                        rSensor = 'ES_B_11_429_3E90';
                    case 'ES_B_06_419_7C09'
                        rSensor = 'ES_B_06_419_7C09';
                    case 'ES_B_02_412_3E68'
                        rSensor = 'ES_B_02_412_3E68';
                    case 'ES_B_12_431_7BC2'
                        rSensor = 'ES_B_12_431_7BC2';
                    case 'ES_B_11_428_3EA4'
                        rSensor = 'ES_B_11_428_3EA4';
                    case 'ES_B_08_422_7BDC'
                        rSensor = 'ES_B_08_422_7BDC';
                    case 'ES_B_05_417_7C13'
                        rSensor = 'ES_B_05_417_7C13';
                    case 'ES_B_10_427_7B90'
                        rSensor = 'ES_B_05_417_7C13';
                    case 'ES_C_13_302_C88E'
                        rSensor = 'ES_C_13_302_C88E';
                    case 'ES_B_07_421_7C17'
                        rSensor = 'ES_B_07_421_7C17';
                    case 'ES_B_04_415_7BD1'
                        rSensor = 'ES_B_04_415_7BD1';
                    case 'ES_B_07_420_7E1D'
                        rSensor = 'ES_B_07_420_7E1D';
                    case 'ES_B_10_426_7E1E'
                        rSensor = 'ES_B_10_426_7E1E';
                    case 'ES_B_12_430_44DE'
                        rSensor = 'ES_B_12_430_44DE';
                    case 'ES_B_06_418_7BED'
                        rSensor = 'ES_B_06_418_7BED';
                    case 'ES_B_04_414_7C00'
                        rSensor = 'ES_B_04_414_7C00';
                    case 'ES_B_05_416_7C15'
                        rSensor = 'ES_B_05_416_7C15';
                    case 'ES_B_09_425_3E8D'
                        rSensor = 'ES_B_09_425_3E8D';
                    case 'ES_B_01_411_7E39'
                        rSensor = 'ES_B_01_411_7E39';
                    case 'ES_B_09_424_B74C'
                        rSensor = 'ES_B_09_424_B74C';
                    case 'ES_B_08_423_7BE2'
                        rSensor = 'ES_B_08_423_7BE2';
                    case 'ES_A_13_276_7C44'
                        rSensor = 'ES_A_13_276_7C44';
                    case 'ES_A_09_298_7BC5'
                        rSensor = 'ES_A_09_298_7BC5';
                    case 'ES_A_07_293_3E72'
                        rSensor = 'ES_A_07_293_3E72';
                    case 'ES_B_05_185_8BB4'
                        rSensor = 'ES_B_05_185_8BB4';
                    case 'ES_A_03_153_CE2A'
                        rSensor = 'ES_A_03_153_CE2A';
                    case 'ES_A_12_301_7BB6'
                        rSensor = 'ES_A_12_301_7BB6';
                    case 'ES_A_11_300_7BCD'
                        rSensor = 'ES_A_11_300_7BCD';
                    case 'ES_A_10_234_3E9E'
                        rSensor = 'ES_A_10_234_3E9E';
                end
                switch app.WaspSensor.Value
                    case 'Battery'
                        rSubSensor = 'BAT';
                    case 'Luminosity'
                        rSubSensor = 'LUM';
                    case 'Temperature Celsius'
                        rSubSensor = 'TCA';
                    case 'Humidity'
                        rSubSensor = 'HUMA';
                    case 'Oxygen'
                        rSubSensor = 'O2';
                    case 'Air Pollutants 2'
                        rSubSensor = 'AP2';
                    case 'Hydrocarbons'
                        rSubSensor = 'VOC';
                    case 'Carbon Dioxide'
                        rSubSensor = 'CO2';
                end
            end
            if (strcmp(rFamily,'logins'))
                switch app.PCUnit.Value
                    case 'E1-04201'
                        rSensor = 'E1-04201';
                    case 'E1-05204'
                        rSensor = 'E1-05204';
                    case 'E1-05300'
                        rSensor = 'E1-05300';
                    case 'E1-053L0'
                        rSensor = 'E1-053L0';
                    case 'E1-053V0'
                        rSensor = 'E1-053V0';
                    case 'E1-05402'
                        rSensor = 'E1-05402';
                    case 'E1-06101'
                        rSensor = 'E1-06101';
                    case 'E1-06102'
                        rSensor = 'E1-06102';
                    case 'E1-06103'
                        rSensor = 'E1-06103';
                    case 'E1-061V2'
                        rSensor = 'E1-061V2';
                    case 'E1-07403'
                        rSensor = 'E1-07403';
                    case 'E1-07404'
                        rSensor = 'E1-07404';
                    case 'E1-07405'
                        rSensor = 'E1-07405';
                    case 'E1-07406'
                        rSensor = 'E1-07406';
                    case 'E1-08409'
                        rSensor = 'E1-08409';
                    case 'E1-09405'
                        rSensor = 'E1-09405';
                    case 'E1-10104'
                        rSensor = 'E1-10104';
                    case 'E1-10403'
                        rSensor = 'E1-10403';
                    case 'E1-11300'
                        rSensor = 'E1-11300';
                    case 'E1-11302'
                        rSensor = 'E1-11302';
                    case 'E1-11403'
                        rSensor = 'E1-11403';
                    case 'E1-B1100'
                        rSensor = 'E1-B1100';
                    case 'E1-B1102'
                        rSensor = 'E1-B1102';
                    case 'E1-B1103'
                        rSensor = 'E1-B1103';
                    case 'E1-B11V2'
                        rSensor = 'E1-B11V2';
                    case 'E1-B11V3'
                        rSensor = 'E1-B11V3';
                    case 'E1-B1203'
                        rSensor = 'E1-B1203';
                    case 'E1-B1204'
                        rSensor = 'E1-B1204';
                    case 'E1-B1400'
                        rSensor = 'E1-B1400';
                    case 'E1-B1401'
                        rSensor = 'E1-B1401';
                    case 'E1-B1402'
                        rSensor = 'E1-B1402';
                    case 'E1-B1403'
                        rSensor = 'E1-B1403';
                    case 'E1-B14V0'
                        rSensor = 'E1-B14V0';
                    case 'E1-B14V1'
                        rSensor = 'E1-B14V1';
                    case 'E1-B14V2'
                        rSensor = 'E1-B14V2';
                    case 'E1-B14V3'
                        rSensor = 'E1-B14V3';
                end
            end
            
            if (strcmp(rFamily,'embed'))
                switch app.EmbedUnit.Value
                    case 'ESD12_1'
                        rSensor = 'ESD12_1';
                        switch app.Embed1Sensor.Value
                            case 'SG.12SW.124B'
                                rSubSensor = 'SG.12SW.124B';
                            case 'SG.12SW.124BR (Ohms)'
                                rSubSensor = 'SG.12SW.124BR+%28Ohms%29';
                            case 'SG.12SW.135C'
                                rSubSensor = 'SG.12SW.135C';
                            case 'SG.12SW.135CR (Ohms)'
                                rSubSensor = 'SG.12SW.135CR+%28Ohms%29';
                            case 'SG.12SW.223T'
                                rSubSensor = 'SG.12SW.223T';
                            case 'SG.12SW.223TR (Ohms)'
                                rSubSensor = 'SG.12SW.223TR+%28Ohms%29';
                            case 'SG.12SW.137C'
                                rSubSensor = 'SG.12SW.137C';
                            case 'SG.12SW.137CR (Ohms)'
                                rSubSensor = 'SG.12SW.137CR+%28Ohms%29';
                            case 'SG.12SW.136B'
                                rSubSensor = 'SG.12SW.136B';
                            case 'SG.12SW.136BR (Ohms)'
                                rSubSensor = 'SG.12SW.136BR+%28Ohms%29';
                            case 'SG.12SW.224T'
                                rSubSensor = 'SG.12SW.224T';
                            case 'SG.12SW.224TR (Ohms)'
                                rSubSensor = 'SG.12SW.224TR+%28Ohms%29';
                            case 'CI.12S.R132L (mV)'
                                rSubSensor = 'CI.12S.R132L+%28mV%29';
                        end
                    case 'ESD12_2'
                        rSensor = 'ESD12_2';
                        switch app.Embed2Sensor.Value
                            case 'SG.12C.127B'
                                rSubSensor = 'SG.12C.127B';
                            case 'SG.12C.127BR (Ohms)'
                                rSubSensor = 'SG.12C.127BR+%28Ohms%29';
                            case 'SG.12C.128T'
                                rSubSensor = 'SG.12C.128T';
                            case 'SG.12C.128TR (Ohms)'
                                rSubSensor = 'SG.12C.128TR+%28Ohms%29';
                            case 'SG.12C.133L'
                                rSubSensor = 'SG.12C.133L';
                            case 'SG.12C.133LR (Ohms)'
                                rSubSensor = 'SG.12C.133LR+%28Ohms%29';
                            case 'CI.12S.R268L (mV)'
                                rSubSensor = 'CI.12S.R268L+%28mV%29';
                        end
                    case 'ESD13_1'
                        rSensor = 'ESD13_1';
                        switch app.Embed3Sensor.Value
                            case 'SG.13S.167L'
                                rSubSensor = 'SG.13S.167L';
                            case 'SG.13S.167LR (Ohms)'
                                rSubSensor = 'SG.13S.167LR+%28Ohms%29';
                            case 'SG.13S.171L'
                                rSubSensor = 'SG.13S.171L';
                            case 'SG.13S.171LR (Ohms)'
                                rSubSensor = 'SG.13S.171LR+%28Ohms%29';
                            case 'SG.13S.172T'
                                rSubSensor = 'SG.13S.172T';
                            case 'SG.13S.172TR (Ohms)'
                                rSubSensor = 'SG.13S.172TR+%28Ohms%29';
                            case 'SG.13T.144'
                                rSubSensor = 'SG.13T.144';
                            case 'SG.13T.144R (Ohms)'
                                rSubSensor = 'SG.13T.144R+%28Ohms%29';
                            case 'SG.13T.145'
                                rSubSensor = 'SG.13T.145';
                            case 'SG.13T.145R (Ohms)'
                                rSubSensor = 'SG.13T.145R+%28Ohms%29';
                            case 'SG.13T.146'
                                rSubSensor = 'SG.13T.146';
                            case 'SG.13T.146R (Ohms)'
                                rSubSensor = 'SG.13T.146R+%28Ohms%29';
                            case 'SG.13T.147'
                                rSubSensor = 'SG.13T.147';
                            case 'SG.13T.147R (Ohms)'
                                rSubSensor = 'SG.13T.147R+%28Ohms%29';
                            case 'CI.13S.R270L (mV)'
                                rSubSensor = 'CI.13S.R270L+%28mV%29';
                        end
                    case 'ESDB4_1'
                        rSensor = 'ESDB4_1';
                        switch app.Embed4Sensor.Value
                            case 'SG.FTP.01'
                                rSubSensor = 'SG.FTP.01';
                            case 'SG.FTP.01R (Ohms)'
                                rSubSensor = 'SG.FTP.01R+%28Ohms%29';
                            case 'SG.FTP.02'
                                rSubSensor = 'SG.FTP.02';
                            case 'SG.FTP.02R (Ohms)'
                                rSubSensor = 'SG.FTP.02R+%28Ohms%29';
                            case 'SG.FTP.03'
                                rSubSensor = 'SG.FTP.03';
                            case 'SG.FTP.03R (Ohms)'
                                rSubSensor = 'SG.FTP.03R+%28Ohms%29';
                            case 'SG.FTP.04'
                                rSubSensor = 'SG.FTP.04';
                            case 'SG.FTP.04R (Ohms)'
                                rSubSensor = 'SG.FTP.04R+%28Ohms%29';
                            case 'SG.FTP.06'
                                rSubSensor = 'SG.FTP.06';
                            case 'SG.FTP.06R (Ohms)'
                                rSubSensor = 'SG.FTP.06R+%28Ohms%29';
                            case 'SG.B4SW.65C'
                                rSubSensor = 'SG.B4SW.65C';
                            case 'SG.B4SW.65C5 (Ohms)'
                                rSubSensor = 'SG.B4SW.65C5+%28Ohms%29';
                            case 'SG.B4SW.205T'
                                rSubSensor = 'SG.B4SW.205T';
                            case 'SG.B4SW.205TR (Ohms)'
                                rSubSensor = 'SG.B4SW.205TR+%28Ohms%29';
                            case 'CI.FTP.13 (mV)'
                                rSubSensor = 'CI.FTP.13+%28mV%29';
                            case 'CI.FTP.14 (mV)'
                                rSubSensor = 'CI.FTP.14+%28mV%29';
                            case 'CI.FTP.68 (mV)'
                                rSubSensor = 'CI.FTP.68+%28mV%29';
                        end
                    case 'ESDB4_2'
                        rSensor = 'ESDB4_2';
                        switch app.Embed5Sensor.Value
                            case 'SG.B4C.59T'
                                rSubSensor = 'SG.B4C.59T';
                            case 'SG.B4C.59TR (Ohms)'
                                rSubSensor = 'SG.B4C.59TR+%28Ohms%29';
                            case 'SG.B4SW.60B'
                                rSubSensor = 'SG.B4SW.60B';
                            case 'SG.B4SW.60BR (Ohms)'
                                rSubSensor = 'SG.B4SW.60BR+%28Ohms%29';
                            case 'SG.B4SW.61C'
                                rSubSensor = 'SG.B4SW.61C';
                            case 'SG.B4SW.61CR (Ohms)'
                                rSubSensor = 'SG.B4SW.61CR+%28Ohms%29';
                            case 'SG.B4SW.66B'
                                rSubSensor = 'SG.B4SW.66B';
                            case 'SG.B4SW.66BR (Ohms)'
                                rSubSensor = 'SG.B4SW.66BR+%28Ohms%29';
                            case 'SG.B4SW.67C'
                                rSubSensor = 'SG.B4SW.67C';
                            case 'SG.B4SW.67CR (Ohms)'
                                rSubSensor = 'SG.B4SW.67CR+%28Ohms%29';
                            case 'SG.B4SW.202C'
                                rSubSensor = 'SG.B4SW.202C';
                            case 'SG.B4SW.202CR (Ohms)'
                                rSubSensor = 'SG.B4SW.202CR+%28Ohms%29';
                            case 'SG.B4SW.203T'
                                rSubSensor = 'SG.B4SW.203T';
                            case 'SG.B4SW.203TR (Ohms)'
                                rSubSensor = 'SG.B4SW.203TR+%28Ohms%29';
                            case 'SG.B4SW.204T'
                                rSubSensor = 'SG.B4SW.204T';
                            case 'SG.B4SW.204TR (Ohms)'
                                rSubSensor = 'SG.B4SW.204TR+%28Ohms%29';
                            case 'SG.FTP.09'
                                rSubSensor = 'SG.FTP.09';
                            case 'SG.FTP.09R (Ohms)'
                                rSubSensor = 'SG.FTP.09R+%28Ohms%29';
                            case 'SG.FTP.10'
                                rSubSensor = 'SG.FTP.10';
                            case 'SG.FTP.10R (Ohms)'
                                rSubSensor = 'SG.FTP.10R+%28Ohms%29';
                            case 'SG.FTP.11'
                                rSubSensor = 'SG.FTP.11';
                            case 'SG.FTP.11R (Ohms)'
                                rSubSensor = 'SG.FTP.11R+%28Ohms%29';
                            case 'SG.FTP.12'
                                rSubSensor = 'SG.FTP.12';
                            case 'SG.FTP.12R (Ohms)'
                                rSubSensor = 'SG.FTP.12R+%28Ohms%29';
                            case 'SG.FTSF.193'
                                rSubSensor = 'SG.FTSF.193';
                            case 'SG.FTSF.193R (Ohms)'
                                rSubSensor = 'SG.FTSF.193R+%28Ohms%29';
                            case 'SG.B4SW.208T'
                                rSubSensor = 'SG.B4SW.208T';
                            case 'SG.B4SW.208TR (Ohms)'
                                rSubSensor = 'SG.B4SW.208TR+%28Ohms%29';
                            case 'SG.FTP.07'
                                rSubSensor = 'SG.FTP.07';
                            case 'SG.FTP.07R (Ohms)'
                                rSubSensor = 'SG.FTP.07R+%28Ohms%29';
                            case 'SG.FTP.08'
                                rSubSensor = 'SG.FTP.08';
                            case 'SG.FTP.08R (Ohms)'
                                rSubSensor = 'SG.FTP.08R+%28Ohms%29';
                            case 'CI.FTSF.198 (mV)'
                                rSubSensor = 'CI.FTSF.198+%28mV%29';
                        end
                end
            end
            if (strcmp(rFamily,'turbine'))
                switch app.TurbineUnit.Value
                    case 'VAWT Shaft Base'
                        rSensor = 'DAQ13.01';
                    case 'VAWT Shaft Top'
                        rSensor = 'DAQ13.02';
                end
                switch app.TurbineSensor.Value
                    case 'Vibration X-Axis (extremum)'
                        rSubSensor = 'AIN1_extremum';
                    case 'Vibration X-Axis (average)'
                        rSubSensor = 'AIN1_average';
                    case 'Vibration Y-Axis (extremum)'
                        rSubSensor = 'AIN2_extremum';
                    case 'Vibration Y-Axis (average)'
                        rSubSensor = 'AIN2_average';
                    case 'Vibration Z-Axis (extremum)'
                        rSubSensor = 'AIN3_extremum';
                    case 'Vibration Z-Axis (average)'
                        rSubSensor = 'AIN3_average';
                    case 'Microphone (extremum)'
                        rSubSensor = 'AIN4_extremum';
                    case 'Microphone (average)'
                        rSubSensor = 'AIN4_average';
                end
            end
            % Format the url for the EIF API system.
            url = 'http://eif-research.feit.uts.edu.au/api/dl/';
            url_full = strcat(url,'?rFromDate=',rFromDate, ...
                '&rToDate=',rToDate, ...
                '&rFamily=',rFamily, ...
                '&rSensor=',rSensor, ...
                '&rSubSensor=', rSubSensor);
            % Save the data from the EIF API system by downloading and saving
            % the .csv file locally, then plotting to screen.
            options = weboptions('Timeout',Inf);
            websave('local3.csv',url_full,options);
            M = csvread('local3.csv',0,1);
            mean_x =  mean(M);
            std_x = std(M);
            min_x = min(M);
            max_x = max(M);
            sum_x = sum(M);
            z = (app.ZPoint.Value - mean_x)/std_x
            p = normcdf(z)
            app.ProbBe.Value = sprintf('%d', p)
            x = [1:1:length(M)]'
            b1 = x\M
            yClac1  = b1 * x;
            X = [ones(length(x),1) x];
            b = X\M
            yCalc2 = X*b;
            plot(app.UIAxes,M)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x, yCalc2, '--')
            pan (app.UIAxes, 'on')
            zoom (app.UIAxes, 'on')
            plot(app.UIAxes, ones(length(M),1)*mean_x, 'color', [0.47 0.67 0.19])
            plot(app.UIAxes, ones(length(M),1)*std_x, 'color', [0.93 0.69 0.13])
            legend(app.UIAxes, 'Data','Linear Regression','Mean', 'Standard Deviation');
            % Showing min, max, mean, std, sum in text boxes
            app.MeanTextArea.Value = sprintf('%d',mean_x);
            app.STDTextArea.Value = sprintf('%d',mean_x);
            app.SumTextArea.Value = sprintf('%d', sum_x);
            
            
        end

        % Button pushed function: GoCurrentTime
        function GoCurrentTimePushed(app, event)
            while app.KeepUp.Value == 'On'
                
                c = clock;
                year  = num2str(c(1))
                month = num2str(c(2))
                day = num2str(c(3))
                hour = num2str(c(4))
                minute = num2str(c(5))
                current_time = strcat(year,'-',month,'-',day,'T',hour,'%3A',minute,'%3A','00')
                year  = num2str(c(1) - app.YearAgo.Value)
                month = num2str(c(2) - app.MonthAgo.Value)
                if month < 0
                    month = 12 + month
                end
                day = num2str(c(3) - app.DayAgo.Value)
                if day < 0
                    day = 30 + day
                end
                hour = num2str(c(4) - app.HourAgo.Value)
                if hour < 0
                    hour = 24 + hour
                end
                minute = num2str(c(5) - app.MinuteAgo.Value)
                if minute < 0
                    minute = 60 + minute
                end
                
                
                past_time = strcat(year,'-',month,'-',day,'T',hour,'%3A',minute,'%3A','00');
                rFromDate = past_time
                rToDate = current_time
                
                % Format the sensor family that was chosen from the list box.
                switch app.SensorFamilyListBox.Value
                    case 'People Counter'
                        rFamily = 'people';
                    case 'Weather Station'
                        rFamily = 'weather';
                    case 'Co-Gen Renewables'
                        rFamily = 'cogen';
                    case 'Microgrid Nemo'
                        rFamily = 'nemo';
                    case 'VAWT Power Output'
                        rFamily = 'crompton';
                    case 'Waspmote'
                        rFamily = 'wasp';
                    case 'PCLabs Logins'
                        rFamily = 'logins';
                end
                rSubSensor = [];
                % Sub sensor initalised in case it is chosen.
                % Format the people counter sensor family, if it was chosen.
                if (strcmp(rFamily,'people'))
                    rSubSensor = [];
                    switch app.PeopleUnit.Value
                        case 'PC00.05 (In)'
                            rSensor = '+PC00.05+%28In%29';
                        case 'PC00.05 (Out)'
                            rSensor = '+PC00.05+%28Out%29';
                        case 'PC00.06 (In)'
                            rSensor = '+PC00.06+%28In%29';
                        case 'PC00.06 (Out)'
                            rSensor = '+PC00.06+%28Out%29';
                        case 'PC00.07 (In)'
                            rSensor = '+PC00.07+%28In%29';
                        case 'PC00.07 (Out)'
                            rSensor = '+PC00.07+%28Out%29';
                        case 'PC00.08 (In)'
                            rSensor = '+PC00.08+%28In%29';
                        case 'PC00.08 (Out)'
                            rSensor = '+PC00.08+%28Out%29';
                        case 'PC00.09 (In)'
                            rSensor = '+PC00.09+%28In%29';
                        case 'PC00.09 (Out)'
                            rSensor = '+PC00.09+%28Out%29';
                        case 'PC01.11 (In)'
                            rSensor = '+PC01.11+%28In%29';
                        case 'PC01.11 (Out)'
                            rSensor = '+PC01.11+%28Out%29';
                        case 'PC01.12 (In)'
                            rSensor = '+PC01.12+%28In%29';
                        case 'PC01.12 (Out)'
                            rSensor = '+PC01.12+%28Out%29';
                        case 'PC01.13 (In)'
                            rSensor = '+PC01.13+%28In%29';
                        case 'PC01.13 (Out)'
                            rSensor = '+PC01.13+%28Out%29';
                        case 'PC02.14 (In)'
                            rSensor = '+PC02.14+%28In%29';
                        case 'PC02.14 (Out)'
                            rSensor = '+PC02.14+%28Out%29';
                        case 'PC02.15 (In)'
                            rSensor = '+PC02.15+%28In%29';
                        case 'PC02.15 (Out)'
                            rSensor = '+PC02.15+%28Out%29';
                        case 'PC02.16 (In)'
                            rSensor = '+PC02.16+%28In%29';
                        case 'PC02.16 (Out)'
                            rSensor = '+PC02.16+%28Out%29';
                        case 'PC03.17 (In)'
                            rSensor = '+PC03.17+%28In%29';
                        case 'PC03.17 (Out)'
                            rSensor = '+PC03.17+%28Out%29';
                        case 'PC04.20 (In)'
                            rSensor = '+PC04.20+%28In%29';
                        case 'PC04.20 (Out)'
                            rSensor = '+PC04.20+%28Out%29';
                        case 'PC05.21 (In)'
                            rSensor = '+PC05.21+%28In%29';
                        case 'PC05.21 (Out)'
                            rSensor = '+PC05.21+%28Out%29';
                        case 'PC05.22 (In)'
                            rSensor = '+PC05.22+%28In%29';
                        case 'PC05.22 (Out)'
                            rSensor = '+PC05.22+%28Out%29';
                        case 'PC05.23 (In)'
                            rSensor = '+PC05.23+%28In%29';
                        case 'PC05.23 (Out)'
                            rSensor = '+PC05.23+%28Out%29';
                        case 'PC05.24 (In)'
                            rSensor = '+PC05.24+%28In%29';
                        case 'PC05.24 (Out)'
                            rSensor = '+PC05.24+%28Out%29';
                        case 'PC08.25 (In)'
                            rSensor = '+PC08.25+%28In%29';
                        case 'PC08.25 (Out)'
                            rSensor = '+PC08.25+%28Out%29';
                        case 'PC09.26 (In)'
                            rSensor = '+PC09.26+%28In%29';
                        case 'PC09.26 (Out)'
                            rSensor = '+PC09.26+%28Out%29';
                        case 'PC09.27 (In)'
                            rSensor = '+PC09.27+%28In%29';
                        case 'PC09.27 (Out)'
                            rSensor = '+PC09.27+%28Out%29';
                        case 'PC09.28 (In)'
                            rSensor = '+PC09.28+%28In%29';
                        case 'PC09.28 (Out)'
                            rSensor = '+PC09.28+%28Out%29';
                        case 'PC09.29 (In)'
                            rSensor = '+PC09.29+%28In%29';
                        case 'PC09.29 (Out)'
                            rSensor = '+PC09.29+%28Out%29';
                        case 'PC10.30 (In)'
                            rSensor = '+PC10.30+%28In%29';
                        case 'PC10.30 (Out)'
                            rSensor = '+PC10.30+%28Out%29';
                        case 'PC11.31 (In)'
                            rSensor = '+PC11.31+%28In%29';
                        case 'PC11.31 (Out)'
                            rSensor = '+PC11.31+%28Out%29';
                        case 'PC11.32 (In)'
                            rSensor = '+PC11.32+%28In%29';
                        case 'PC11.32 (Out)'
                            rSensor = '+PC11.32+%28Out%29';
                        case 'PC11.33 (In)'
                            rSensor = '+PC11.33+%28In%29';
                        case 'PC11.33 (Out)'
                            rSensor = '+PC11.33+%28Out%29';
                        case 'PCB1.01 (In)'
                            rSensor = '+PCB1.01+%28In%29';
                        case 'PCB1.01 (Out)'
                            rSensor = '+PCB1.01+%28Out%29';
                        case 'PCB1.02 (In)'
                            rSensor = '+PCB1.02+%28In%29';
                        case 'PCB1.02 (Out)'
                            rSensor = '+PCB1.02+%28Out%29';
                    end
                end
                % Format the weather station sensor family, if it was chosen.
                if (strcmp(rFamily,'weather'))
                    rSubSensor = [];
                    switch app.WeatherUnit.Value
                        case 'Wind Direction'
                            rSensor = 'WD';
                        case 'Air Temperature'
                            rSensor = 'AT';
                        case 'Solar Radiation'
                            rSensor = 'SR';
                        case 'Rain Gauge'
                            rSensor = 'RG';
                        case 'Solar Voltage'
                            rSensor = 'SV';
                        case 'Peak Wind Gust'
                            rSensor = 'PW';
                        case 'UVA Radiation'
                            rSensor = 'UV';
                        case 'WDIR Cosine'
                            rSensor = 'WC';
                        case 'WDIR Sine'
                            rSensor = 'WS';
                        case 'Battery Voltage'
                            rSensor = 'BV';
                        case 'Load Current'
                            rSensor = 'LC';
                        case 'Charge Current'
                            rSensor = 'CC';
                        case 'Inst. Wind Speed'
                            rSensor = 'IWS';
                        case 'Inst. Wind Direction'
                            rSensor = 'IWD';
                        case 'N/-S Wind Sp (VCos)'
                            rSensor = 'NW';
                        case 'E/-W Wind Sp (VSin)'
                            rSensor = 'EW';
                        case 'Rain Total from 9am'
                            rSensor = 'RT';
                        case 'Communications'
                            rSensor = 'Co';
                    end
                end
                
                if (strcmp(rFamily,'cogen'))
                    switch app.CoGenUnit.Value
                        case 'Chiller'
                            rSensor = 'Chiller';
                            if (strcmp(rSensor,'Chiller'))
                                switch app.CoGenChiller.Value
                                    case 'Chiller Instant Power'
                                        rSubSensor = '1';
                                    case 'Chiller Lifetime Energy'
                                        rSubSensor = '2';
                                    case 'CH Instant Power Rejected'
                                        rSubSensor = '5';
                                    case 'CH Lifetime Energy Rejected'
                                        rSubSensor = '6';
                                end
                            end
                        case 'Domestic Hot Water'
                            rSensor = 'Domestic+Hot+Water';
                            if (strcmp(rSensor,'Domestic+Hot+Water'))
                                switch app.DomHotWater.Value
                                    case 'DHW Instant Power'
                                        rSubSensor = '3';
                                    case 'DHW Lifetime Energy'
                                        rSubSensor = '4';
                                end
                            end
                            
                        case 'Solar Thermal Collectors'
                            rSensor = 'Solar+Thermal+Collectors';
                            if (strcmp(rSensor,'Solar+Thermal+Collectors'))
                                switch app.SolTherColl.Value
                                    case 'STC Instant Power'
                                        rSubSensor = '7';
                                    case 'STC Lifetime Energy'
                                        rSubSensor = '8';
                                end
                            end
                    end
                end
                
                if (strcmp(rFamily,'nemo'))
                    switch app.NemoUnit.Value
                        case 'MDB EIF Output'
                            rSensor = 'MDB+EIF+Output';
                            if (strcmp(rSensor, 'MDB+EIF+Output'))
                                switch app.NemoSensors.Value
                                    case 'Phase 1 Voltage'
                                        rSubSensor = '4096';
                                    case 'Phase 2 Voltage'
                                        rSubSensor = '4098';
                                    case 'Phase 3 Voltage'
                                        rSubSensor = '4100';
                                    case 'Neutral Current'
                                        rSubSensor = '4108';
                                    case '3-phase Active Power'
                                        rSubSensor = '4116';
                                    case '3-phase Reactive Power'
                                        rSubSensor = '4118';
                                    case '3-phase Apparent Power'
                                        rSubSensor = '4120';
                                    case '3-Phase Positive Active Energy'
                                        rSubSensor = '4124';
                                    case '3-Phase Positive Reactive Energy'
                                        rSubSensor = '4126';
                                    case '3-Phase Negative Active Energy'
                                        rSubSensor = '4128';
                                    case '3-Phase Negative Reactive Energy'
                                        rSubSensor = '4130';
                                    case '3-phase Power Factor'
                                        rSubSensor = '4132';
                                    case 'Frequency'
                                        rSubSensor = '4134';
                                    case '3-phase Average Power'
                                        rSubSensor = '4135';
                                    case '3-phase Peak Maximum Demand'
                                        rSubSensor = '4137';
                                        
                                end
                            end
                    end
                end
                if (strcmp(rFamily,'crompton'))
                    switch app.VAWTUnit.Value
                        case 'VAWT'
                            rSensor = 'VAWT';
                            if (strcmp(rSensor, 'VAWT'))
                                switch app.VAWTSensor.Value
                                    case 'Phase 1 line to neutral volts'
                                        rSubSensor = '1';
                                    case 'Phase 2 line to neutral volts'
                                        rSubSensor = '3';
                                    case 'Phase 3 line to neutral volts'
                                        rSubSensor = '5';
                                    case 'Phase 1 current'
                                        rSubSensor = '7';
                                    case 'Phase 2 current'
                                        rSubSensor = '9';
                                    case 'Phase 3 current'
                                        rSubSensor = '11';
                                    case 'Phase 1 power'
                                        rSubSensor = '13';
                                    case 'Phase 2 power'
                                        rSubSensor = '15';
                                    case 'Phase 3 power'
                                        rSubSensor = '17';
                                    case 'Phase 1 power factor'
                                        rSubSensor = '31';
                                    case 'Phase 2 power factor'
                                        rSubSensor = '33';
                                    case 'Phase 3 power factor'
                                        rSubSensor = '35';
                                    case 'Sum of line currents'
                                        rSubSensor = '49';
                                    case 'Total system power'
                                        rSubSensor = '53';
                                    case 'Frequency of supply voltages'
                                        rSubSensor = '71';
                                    case 'Average line to line volts'
                                        rSubSensor = '207';
                                end
                            end
                    end
                end
                
                if (strcmp(rFamily,'wasp'))
                    switch app.WaspUnit.Value
                        case 'ES_B_11_429_3E90'
                            rSensor = 'ES_B_11_429_3E90';
                        case 'ES_B_06_419_7C09'
                            rSensor = 'ES_B_06_419_7C09';
                        case 'ES_B_02_412_3E68'
                            rSensor = 'ES_B_02_412_3E68';
                        case 'ES_B_12_431_7BC2'
                            rSensor = 'ES_B_12_431_7BC2';
                        case 'ES_B_11_428_3EA4'
                            rSensor = 'ES_B_11_428_3EA4';
                        case 'ES_B_08_422_7BDC'
                            rSensor = 'ES_B_08_422_7BDC';
                        case 'ES_B_05_417_7C13'
                            rSensor = 'ES_B_05_417_7C13';
                        case 'ES_B_10_427_7B90'
                            rSensor = 'ES_B_05_417_7C13';
                        case 'ES_C_13_302_C88E'
                            rSensor = 'ES_C_13_302_C88E';
                        case 'ES_B_07_421_7C17'
                            rSensor = 'ES_B_07_421_7C17';
                        case 'ES_B_04_415_7BD1'
                            rSensor = 'ES_B_04_415_7BD1';
                        case 'ES_B_07_420_7E1D'
                            rSensor = 'ES_B_07_420_7E1D';
                        case 'ES_B_10_426_7E1E'
                            rSensor = 'ES_B_10_426_7E1E';
                        case 'ES_B_12_430_44DE'
                            rSensor = 'ES_B_12_430_44DE';
                        case 'ES_B_06_418_7BED'
                            rSensor = 'ES_B_06_418_7BED';
                        case 'ES_B_04_414_7C00'
                            rSensor = 'ES_B_04_414_7C00';
                        case 'ES_B_05_416_7C15'
                            rSensor = 'ES_B_05_416_7C15';
                        case 'ES_B_09_425_3E8D'
                            rSensor = 'ES_B_09_425_3E8D';
                        case 'ES_B_01_411_7E39'
                            rSensor = 'ES_B_01_411_7E39';
                        case 'ES_B_09_424_B74C'
                            rSensor = 'ES_B_09_424_B74C';
                        case 'ES_B_08_423_7BE2'
                            rSensor = 'ES_B_08_423_7BE2';
                        case 'ES_A_13_276_7C44'
                            rSensor = 'ES_A_13_276_7C44';
                        case 'ES_A_09_298_7BC5'
                            rSensor = 'ES_A_09_298_7BC5';
                        case 'ES_A_07_293_3E72'
                            rSensor = 'ES_A_07_293_3E72';
                        case 'ES_B_05_185_8BB4'
                            rSensor = 'ES_B_05_185_8BB4';
                        case 'ES_A_03_153_CE2A'
                            rSensor = 'ES_A_03_153_CE2A';
                        case 'ES_A_12_301_7BB6'
                            rSensor = 'ES_A_12_301_7BB6';
                        case 'ES_A_11_300_7BCD'
                            rSensor = 'ES_A_11_300_7BCD';
                        case 'ES_A_10_234_3E9E'
                            rSensor = 'ES_A_10_234_3E9E';
                    end
                    switch app.WaspSensor.Value
                        case 'Battery'
                            rSubSensor = 'BAT';
                        case 'Luminosity'
                            rSubSensor = 'LUM';
                        case 'Temperature Celsius'
                            rSubSensor = 'TCA';
                        case 'Humidity'
                            rSubSensor = 'HUMA';
                        case 'Oxygen'
                            rSubSensor = 'O2';
                        case 'Air Pollutants 2'
                            rSubSensor = 'AP2';
                        case 'Hydrocarbons'
                            rSubSensor = 'VOC';
                        case 'Carbon Dioxide'
                            rSubSensor = 'CO2';
                    end
                end
                if (strcmp(rFamily,'logins'))
                    switch app.PCUnit.Value
                        case 'E1-04201'
                            rSensor = 'E1-04201';
                        case 'E1-05204'
                            rSensor = 'E1-05204';
                        case 'E1-05300'
                            rSensor = 'E1-05300';
                        case 'E1-053L0'
                            rSensor = 'E1-053L0';
                        case 'E1-053V0'
                            rSensor = 'E1-053V0';
                        case 'E1-05402'
                            rSensor = 'E1-05402';
                        case 'E1-06101'
                            rSensor = 'E1-06101';
                        case 'E1-06102'
                            rSensor = 'E1-06102';
                        case 'E1-06103'
                            rSensor = 'E1-06103';
                        case 'E1-061V2'
                            rSensor = 'E1-061V2';
                        case 'E1-07403'
                            rSensor = 'E1-07403';
                        case 'E1-07404'
                            rSensor = 'E1-07404';
                        case 'E1-07405'
                            rSensor = 'E1-07405';
                        case 'E1-07406'
                            rSensor = 'E1-07406';
                        case 'E1-08409'
                            rSensor = 'E1-08409';
                        case 'E1-09405'
                            rSensor = 'E1-09405';
                        case 'E1-10104'
                            rSensor = 'E1-10104';
                        case 'E1-10403'
                            rSensor = 'E1-10403';
                        case 'E1-11300'
                            rSensor = 'E1-11300';
                        case 'E1-11302'
                            rSensor = 'E1-11302';
                        case 'E1-11403'
                            rSensor = 'E1-11403';
                        case 'E1-B1100'
                            rSensor = 'E1-B1100';
                        case 'E1-B1102'
                            rSensor = 'E1-B1102';
                        case 'E1-B1103'
                            rSensor = 'E1-B1103';
                        case 'E1-B11V2'
                            rSensor = 'E1-B11V2';
                        case 'E1-B11V3'
                            rSensor = 'E1-B11V3';
                        case 'E1-B1203'
                            rSensor = 'E1-B1203';
                        case 'E1-B1204'
                            rSensor = 'E1-B1204';
                        case 'E1-B1400'
                            rSensor = 'E1-B1400';
                        case 'E1-B1401'
                            rSensor = 'E1-B1401';
                        case 'E1-B1402'
                            rSensor = 'E1-B1402';
                        case 'E1-B1403'
                            rSensor = 'E1-B1403';
                        case 'E1-B14V0'
                            rSensor = 'E1-B14V0';
                        case 'E1-B14V1'
                            rSensor = 'E1-B14V1';
                        case 'E1-B14V2'
                            rSensor = 'E1-B14V2';
                        case 'E1-B14V3'
                            rSensor = 'E1-B14V3';
                    end
                end
                % Format the url for the EIF API system.
                
                url = 'http://eif-research.feit.uts.edu.au/api/dl/';
                url_full = strcat(url,'?rFromDate=',rFromDate, ...
                    '&rToDate=',rToDate, ...
                    '&rFamily=',rFamily, ...
                    '&rSensor=',rSensor, ...
                    '&rSubSensor=', rSubSensor);
                websave('local2.csv',url_full);
                M = csvread('local2.csv',0,1);
                mean_x =  mean(M);
                std_x = std(M);
                min_x = min(M);
                max_x = max(M);
                sum_x = sum(M);
                x = [1:1:length(M)]';
                b1 = x\M;
                yClac1  = b1 * x;
                X = [ones(length(x),1) x];
                b = X\M;
                yCalc2 = X*b;
                
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, x, yCalc2, '--')
                plot(app.UIAxes,M)
                pan (app.UIAxes, 'on')
                zoom (app.UIAxes, 'on')
                plot(app.UIAxes, ones(length(M),1)*mean_x, 'color', [0.47 0.67 0.19])
                plot(app.UIAxes, ones(length(M),1)*std_x, 'color', [0.93 0.69 0.13])
                legend(app.UIAxes, 'Linear Regression','Data','Mean', 'Standard Deviation');
                % Showing min, max, mean, std, sum in text boxes
                app.MeanTextArea.Value = sprintf('%d',mean_x);
                app.STDTextArea.Value = sprintf('%d',mean_x);
                app.SumTextArea.Value = sprintf('%d', sum_x);
            end
            
            
        end

        % Value changed function: KeepUp
        function KeepUpValueChanged(app, event)
            value = app.KeepUp.Value;
            
        end

        % Value changed function: SensorFamilyListBox
        function SensorFamilyListBoxValueChanged(app, event)
            % Format the UI to hide irrelevant unit and sensor list boxed.
            switch app.SensorFamilyListBox.Value
                % For example, if People Counter is chosen, then turn on the list
                % box with its corresponding units and sensors. Others are turned off.
                case 'People Counter'
                    app.PeopleUnit.Visible = 'on';
                    app.WeatherUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.CoGenUnit.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'Weather Station'
                    app.WeatherUnit.Visible = 'on';
                    app.PeopleUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.CoGenUnit.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'Co-Gen Renewables'
                    app.CoGenUnit.Visible = 'on';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'Microgrid Nemo'
                    app.CoGenUnit.Visible = 'off';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'on';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'on';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'VAWT Power Output'
                    app.CoGenUnit.Visible = 'off';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'on';
                    app.VAWTSensor.Visible = 'on';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'Waspmote'
                    app.CoGenUnit.Visible = 'off';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'on';
                    app.WaspUnit.Visible = 'on';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'PCLabs Logins'
                    app.CoGenUnit.Visible = 'off';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'on';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'Embedded Sensors'
                    app.CoGenUnit.Visible = 'off';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'on';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'off';
                    app.TurbineSensor.Visible = 'off';
                case 'Turbine Effects'
                    app.CoGenUnit.Visible = 'off';
                    app.WeatherUnit.Visible = 'off';
                    app.PeopleUnit.Visible = 'off';
                    app.NemoUnit.Visible = 'off';
                    app.CoGenChiller.Visible = 'off';
                    app.DomHotWater.Visible = 'off';
                    app.SolTherColl.Visible = 'off';
                    app.NemoSensors.Visible = 'off';
                    app.VAWTUnit.Visible = 'off';
                    app.VAWTSensor.Visible = 'off';
                    app.WaspSensor.Visible = 'off';
                    app.WaspUnit.Visible = 'off';
                    app.PCUnit.Visible = 'off';
                    app.EmbedUnit.Visible = 'off';
                    app.Embed1Sensor.Visible = 'off';
                    app.Embed2Sensor.Visible = 'off';
                    app.Embed3Sensor.Visible = 'off';
                    app.Embed4Sensor.Visible = 'off';
                    app.Embed5Sensor.Visible = 'off';
                    app.TurbineUnit.Visible = 'on';
                    app.TurbineSensor.Visible = 'on';
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Units')
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.YearEditField_3Label = uilabel(app.UIFigure);
            app.YearEditField_3Label.Text = 'Year';
            app.YearEditField_3 = uieditfield(app.UIFigure, 'text');
            app.MonthEditField_3Label = uilabel(app.UIFigure);
            app.MonthEditField_3Label.Text = 'Month';
            app.MonthEditField_3 = uieditfield(app.UIFigure, 'text');
            app.DayEditField_3Label = uilabel(app.UIFigure);
            app.DayEditField_3Label.Text = 'Day';
            app.DayEditField_3 = uieditfield(app.UIFigure, 'text');
            app.HourEditField_3Label = uilabel(app.UIFigure);
            app.HourEditField_3Label.Text = 'Hour';
            app.HourEditField_3 = uieditfield(app.UIFigure, 'text');
            app.MinuteEditField_3Label = uilabel(app.UIFigure);
            app.MinuteEditField_3Label.Text = 'Minute';
            app.MinuteEditField_3 = uieditfield(app.UIFigure, 'text');
            app.SecondEditField_3Label = uilabel(app.UIFigure);
            app.SecondEditField_3Label.Text = 'Second';
            app.SecondEditField_3 = uieditfield(app.UIFigure, 'text');
            app.YearEditField_4Label = uilabel(app.UIFigure);
            app.YearEditField_4Label.Text = 'Year';
            app.YearEditField_4 = uieditfield(app.UIFigure, 'text');
            app.MonthEditField_4Label = uilabel(app.UIFigure);
            app.MonthEditField_4Label.Text = 'Month';
            app.MonthEditField_4 = uieditfield(app.UIFigure, 'text');
            app.DayEditField_4Label = uilabel(app.UIFigure);
            app.DayEditField_4Label.Text = 'Day';
            app.DayEditField_4 = uieditfield(app.UIFigure, 'text');
            app.HourEditField_4Label = uilabel(app.UIFigure);
            app.HourEditField_4Label.Text = 'Hour';
            app.HourEditField_4 = uieditfield(app.UIFigure, 'text');
            app.MinuteEditField_4Label = uilabel(app.UIFigure);
            app.MinuteEditField_4Label.Text = 'Minute';
            app.MinuteEditField_4 = uieditfield(app.UIFigure, 'text');
            app.SecondEditField_4Label = uilabel(app.UIFigure);
            app.SecondEditField_4Label.Text = 'Second';
            app.SecondEditField_4 = uieditfield(app.UIFigure, 'text');
            app.FromDateLabel = uilabel(app.UIFigure);
            app.FromDateLabel.Text = 'From Date';
            app.ToDateLabel = uilabel(app.UIFigure);
            app.ToDateLabel.Text = 'To Date';
            app.SensorFamilyLabel = uilabel(app.UIFigure);
            app.SensorFamilyLabel.Text = 'Sensor Family';
            app.SensorFamilyListBox = uilistbox(app.UIFigure);
            app.SensorFamilyListBox.Items = {'People Counter', 'Weather Station', 'Co-Gen Renewables', 'Microgrid Nemo', 'VAWT Power Output', 'Waspmote', 'PCLabs Logins', 'Embedded Sensors', 'Turbine Effects'};
            app.SensorFamilyListBox.ValueChangedFcn = createCallbackFcn(app, @SensorFamilyListBoxValueChanged, true);
            app.SensorFamilyListBox.Value = 'People Counter';
            app.WeatherUnit = uilistbox(app.UIFigure);
            app.WeatherUnit.Items = {'Wind Direction', 'Air Temperature', 'Solar Radiation', 'Rain Gauge', 'Solar Voltage', 'Peak Wind Gust', 'UVA Radiation', 'WDIR Cosine', 'WDIR Sine', 'Battery Voltage', 'Load Current', 'Charge Current', 'Inst. Wind Speed', 'Inst. Wind Direction', 'N/-S Wind Sp (VCos)', 'E/-W Wind Sp (VSin)', 'Rain Total from 9am', 'Communications'};
            app.WeatherUnit.Visible = 'off';
            app.WeatherUnit.Value = 'Wind Direction';
            app.PeopleUnit = uilistbox(app.UIFigure);
            app.PeopleUnit.Items = {'PC00.05 (In)', 'PC00.05 (Out)', 'PC00.06 (In)', 'PC00.06 (Out)', 'PC00.07 (In)', 'PC00.07 (Out)', 'PC00.08 (In)', 'PC00.08 (Out)', 'PC00.09 (In)', 'PC00.09 (Out)', 'PC01.11 (In)', 'PC01.11 (Out)', 'PC01.12 (In)', 'PC01.12 (Out)', 'PC01.13 (In)', 'PC01.13 (Out)', 'PC02.14 (In)', 'PC02.14 (Out)', 'PC02.15 (In)', 'PC02.15 (Out)', 'PC02.16 (In)', 'PC02.16 (Out)', 'PC03.17 (In)', 'PC03.17 (Out)', 'PC04.20 (In)', 'PC04.20 (Out)', 'PC05.21 (In)', 'PC05.21 (Out)', 'PC05.22 (In)', 'PC05.22 (Out)', 'PC05.23 (In)', 'PC05.23 (Out)', 'PC05.24 (In)', 'PC05.24 (Out)', 'PC08.25 (In)', 'PC08.25 (Out)', 'PC09.26 (In)', 'PC09.26 (Out)', 'PC09.27 (In)', 'PC09.27 (Out)', 'PC09.28 (In)', 'PC09.28 (Out)', 'PC09.29 (In)', 'PC09.29 (Out)', 'PC10.30 (In)', 'PC10.30 (Out)', 'PC11.31 (In)', 'PC11.31 (Out)', 'PC11.32 (In)', 'PC11.32 (Out)', 'PC11.33 (In)', 'PC11.33 (Out)', 'PCB1.01 (In)', 'PCB1.01 (Out)', 'PCB1.02 (In)', 'PCB1.02 (Out)'};
            app.PeopleUnit.Value = {};
            app.UnitLabel = uilabel(app.UIFigure);
            app.UnitLabel.Text = 'Unit';
            app.CoGenChiller = uilistbox(app.UIFigure);
            app.CoGenChiller.Items = {'Chiller Instant Power', 'Chiller Lifetime Energy', 'CH Instant Power Rejected', 'CH Lifetime Energy Rejected'};
            app.CoGenChiller.Visible = 'off';
            app.CoGenChiller.Value = 'Chiller Instant Power';
            app.SensorLabel = uilabel(app.UIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.CoGenUnit = uilistbox(app.UIFigure);
            app.CoGenUnit.Items = {'Chiller', 'Domestic Hot Water', 'Solar Thermal Collectors'};
            app.CoGenUnit.ValueChangedFcn = createCallbackFcn(app, @CoGenUnitValueChanged, true);
            app.CoGenUnit.Visible = 'off';
            app.CoGenUnit.Value = 'Chiller';
            app.DomHotWater = uilistbox(app.UIFigure);
            app.DomHotWater.Items = {'DHW Instant Power', 'DHW Lifetime Energy'};
            app.DomHotWater.Visible = 'off';
            app.DomHotWater.Value = 'DHW Instant Power';
            app.SolTherColl = uilistbox(app.UIFigure);
            app.SolTherColl.Items = {'STC Instant Power', 'STC Lifetime Energy'};
            app.SolTherColl.Visible = 'off';
            app.SolTherColl.Value = 'STC Instant Power';
            app.NemoUnit = uilistbox(app.UIFigure);
            app.NemoUnit.Items = {'MDB EIF Output'};
            app.NemoUnit.Visible = 'off';
            app.NemoUnit.Value = 'MDB EIF Output';
            app.NemoSensors = uilistbox(app.UIFigure);
            app.NemoSensors.Items = {'Phase 1 Voltage', 'Phase 2 Voltage', 'Phase 3 Voltage', 'Neutral Current', '3-phase Active Power', '3-phase Reactive Power', '3-phase Apparent Power', '3-Phase Positive Active Energy', '3-Phase Positive Reactive Energy', '3-Phase Negative Active Energy', '3-Phase Negative Reactive Energy', '3-phase Power Factor', 'Frequency', '3-phase Average Power', '3-phase Peak Maximum Demand', '(I1+I2+I3)/3'};
            app.NemoSensors.Visible = 'off';
            app.NemoSensors.Value = 'Phase 1 Voltage';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.VAWTUnit = uilistbox(app.UIFigure);
            app.VAWTUnit.Items = {'VAWT'};
            app.VAWTUnit.Visible = 'off';
            app.VAWTUnit.Value = 'VAWT';
            app.VAWTSensor = uilistbox(app.UIFigure);
            app.VAWTSensor.Items = {'Phase 1 line to neutral volts', 'Phase 2 line to neutral volts', 'Phase 3 line to neutral volts', 'Phase 1 current', 'Phase 2 current', 'Phase 3 current', 'Phase 1 power', 'Phase 2 power', 'Phase 3 power', 'Phase 1 power factor', 'Phase 2 power factor', 'Phase 3 power factor', 'Sum of line currents', 'Total system power', 'Frequency of supply voltages', 'Average line to line volts'};
            app.VAWTSensor.Visible = 'off';
            app.VAWTSensor.Value = 'Phase 1 line to neutral volts';
            app.WaspSensor = uilistbox(app.UIFigure);
            app.WaspSensor.Items = {'Battery', 'Luminosity', 'Temperature Celsius', 'Humidity', 'Oxygen', 'Air Pollutants 2', 'Hydrocarbons', 'Carbon Dioxide'};
            app.WaspSensor.Visible = 'off';
            app.WaspSensor.Value = 'Battery';
            app.WaspUnit = uilistbox(app.UIFigure);
            app.WaspUnit.Items = {'ES_B_11_429_3E90', 'ES_B_06_419_7C09', 'ES_B_02_412_3E68', 'ES_B_12_431_7BC2', 'ES_B_11_428_3EA4', 'ES_B_08_422_7BDC', 'ES_B_05_417_7C13', 'ES_B_10_427_7B90', 'ES_C_13_302_C88E', 'ES_B_07_421_7C17', 'ES_B_04_415_7BD1', 'ES_B_07_420_7E1D', 'ES_B_10_426_7E1E', 'ES_B_12_430_44DE', 'ES_B_06_418_7BED', 'ES_B_04_414_7C00', 'ES_B_05_416_7C15', 'ES_B_09_425_3E8D', 'ES_B_01_411_7E39', 'ES_B_09_424_B74C', 'ES_B_08_423_7BE2', 'ES_A_13_276_7C44', 'ES_A_09_298_7BC5', 'ES_A_07_293_3E72', 'ES_B_05_185_8BB4', 'ES_A_03_153_CE2A', 'ES_A_12_301_7BB6', 'ES_A_11_300_7BCD', 'ES_A_10_234_3E9E'};
            app.WaspUnit.Visible = 'off';
            app.WaspUnit.Value = 'ES_B_11_429_3E90';
            app.PCUnit = uilistbox(app.UIFigure);
            app.PCUnit.Items = {'E1-04201', 'E1-05204', 'E1-05300', 'E1-053L0', 'E1-053V0', 'E1-05402', 'E1-06101', 'E1-06102', 'E1-06103', 'E1-061V2', 'E1-07403', 'E1-07404', 'E1-07405', 'E1-07406', 'E1-08409', 'E1-09405', 'E1-10104', 'E1-10403', 'E1-11300', 'E1-11302', 'E1-11403', 'E1-B1100', 'E1-B1102', 'E1-B1103', 'E1-B11V2', 'E1-B11V3', 'E1-B1203', 'E1-B1204', 'E1-B1400', 'E1-B1401', 'E1-B1402', 'E1-B1403', 'E1-B14V0', 'E1-B14V1', 'E1-B14V2', 'E1-B14V3'};
            app.PCUnit.Visible = 'off';
            app.PCUnit.Value = 'E1-04201';
            app.MeanTextAreaLabel = uilabel(app.UIFigure);
            app.MeanTextAreaLabel.Text = 'Mean';
            app.MeanTextArea = uitextarea(app.UIFigure);
            app.STDTextAreaLabel = uilabel(app.UIFigure);
            app.STDTextAreaLabel.Text = 'STD';
            app.STDTextArea = uitextarea(app.UIFigure);
            app.MininumTextAreaLabel = uilabel(app.UIFigure);
            app.MininumTextAreaLabel.Text = 'Mininum';
            app.MininumTextArea = uitextarea(app.UIFigure);
            app.MaximumTextAreaLabel = uilabel(app.UIFigure);
            app.MaximumTextAreaLabel.Text = 'Maximum';
            app.MaximumTextArea = uitextarea(app.UIFigure);
            app.SumTextAreaLabel = uilabel(app.UIFigure);
            app.SumTextAreaLabel.Text = 'Sum';
            app.SumTextArea = uitextarea(app.UIFigure);
            app.GoCurrentTime = uibutton(app.UIFigure, 'push');
            app.GoCurrentTime.ButtonPushedFcn = createCallbackFcn(app, @GoCurrentTimePushed, true);
            app.GoCurrentTime.Text = 'Go Current';
            app.YearEditField_5Label_2 = uilabel(app.UIFigure);
            app.YearEditField_5Label_2.Text = 'Year';
            app.YearAgo = uieditfield(app.UIFigure, 'numeric');
            app.MonthEditField_5Label = uilabel(app.UIFigure);
            app.MonthEditField_5Label.Text = 'Month';
            app.MonthAgo = uieditfield(app.UIFigure, 'numeric');
            app.DayLabel = uilabel(app.UIFigure);
            app.DayLabel.Text = 'Day';
            app.DayAgo = uieditfield(app.UIFigure, 'numeric');
            app.HourEditField_5Label = uilabel(app.UIFigure);
            app.HourEditField_5Label.Text = 'Hour';
            app.HourAgo = uieditfield(app.UIFigure, 'numeric');
            app.MinuteEditField_5Label = uilabel(app.UIFigure);
            app.MinuteEditField_5Label.Text = 'Minute';
            app.MinuteAgo = uieditfield(app.UIFigure, 'numeric');
            app.SecondEditField_5Label = uilabel(app.UIFigure);
            app.SecondEditField_5Label.Text = 'Second';
            app.SecondAgo = uieditfield(app.UIFigure, 'numeric');
            app.KeepUpToDateSwitchLabel = uilabel(app.UIFigure);
            app.KeepUpToDateSwitchLabel.Text = 'Keep Up To Date';
            app.KeepUp = uiswitch(app.UIFigure, 'toggle');
            app.KeepUp.ValueChangedFcn = createCallbackFcn(app, @KeepUpValueChanged, true);
            app.ZPointEditFieldLabel = uilabel(app.UIFigure);
            app.ZPointEditFieldLabel.Text = 'ZPoint';
            app.ZPoint = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.ProbBe = uieditfield(app.UIFigure, 'text');
            app.EmbedUnit = uilistbox(app.UIFigure);
            app.EmbedUnit.Items = {'ESD12_1', 'ESD12_2', 'ESD13_1', 'ESDB4_1', 'ESDB4_2'};
            app.EmbedUnit.ValueChangedFcn = createCallbackFcn(app, @EmbedUnitValueChanged, true);
            app.EmbedUnit.Visible = 'off';
            app.EmbedUnit.Value = {};
            app.Embed1Sensor = uilistbox(app.UIFigure);
            app.Embed1Sensor.Items = {'SG.12SW.124B', 'SG.12SW.124BR (Ohms)', 'SG.12SW.135C', 'SG.12SW.135CR (Ohms)', 'SG.12SW.223T', 'SG.12SW.223TR (Ohms)', 'SG.12SW.137C', 'SG.12SW.137CR (Ohms)', 'SG.12SW.136B', 'SG.12SW.136BR (Ohms)', 'SG.12SW.224T', 'SG.12SW.224TR (Ohms)', 'CI.12S.R132L (mV)'};
            app.Embed1Sensor.Visible = 'off';
            app.Embed1Sensor.Value = {};
            app.Embed2Sensor = uilistbox(app.UIFigure);
            app.Embed2Sensor.Items = {'SG.12C.127B', 'SG.12C.127BR (Ohms)', 'SG.12C.128T', 'SG.12C.128TR (Ohms)', 'SG.12C.133L', 'SG.12C.133LR (Ohms)', 'CI.12S.R268L (mV)'};
            app.Embed2Sensor.Visible = 'off';
            app.Embed2Sensor.Value = {};
            app.Embed3Sensor = uilistbox(app.UIFigure);
            app.Embed3Sensor.Items = {'SG.13S.167L', 'SG.13S.167LR (Ohms)', 'SG.13S.171L', 'SG.13S.171LR (Ohms)', 'SG.13S.172T', 'SG.13S.172TR (Ohms)', 'SG.13T.144', 'SG.13T.144R (Ohms)', 'SG.13T.145', 'SG.13T.145R (Ohms)', 'SG.13T.146', 'SG.13T.146R (Ohms)', 'SG.13T.147', 'SG.13T.147R (Ohms)', 'CI.13S.R270L (mV)'};
            app.Embed3Sensor.Visible = 'off';
            app.Embed3Sensor.Value = {};
            app.Embed4Sensor = uilistbox(app.UIFigure);
            app.Embed4Sensor.Items = {'SG.FTP.01', 'SG.FTP.01R (Ohms)', 'SG.FTP.02', 'SG.FTP.02R (Ohms)', 'SG.FTP.03', 'SG.FTP.03R (Ohms)', 'SG.FTP.04', 'SG.FTP.04R (Ohms)', 'SG.FTP.06', 'SG.FTP.06R (Ohms)', 'SG.B4SW.65C', 'SG.B4SW.65C5 (Ohms)', 'SG.B4SW.205T', 'SG.B4SW.205TR (Ohms)', 'CI.FTP.13 (mV)', 'CI.FTP.14 (mV)', 'CI.FTP.68 (mV)'};
            app.Embed4Sensor.Visible = 'off';
            app.Embed4Sensor.Value = 'SG.FTP.01';
            app.Embed5Sensor = uilistbox(app.UIFigure);
            app.Embed5Sensor.Items = {'SG.B4C.59T', 'SG.B4C.59TR (Ohms)', 'SG.B4SW.60B', 'SG.B4SW.60BR (Ohms)', 'SG.B4SW.61C', 'SG.B4SW.61CR (Ohms)', 'SG.B4SW.66B', 'SG.B4SW.66BR (Ohms)', 'SG.B4SW.67C', 'SG.B4SW.67CR (Ohms)', 'SG.B4SW.202C', 'SG.B4SW.202CR (Ohms)', 'SG.B4SW.203T', 'SG.B4SW.203TR (Ohms)', 'SG.B4SW.204T', 'SG.B4SW.204TR (Ohms)', 'SG.FTP.09', 'SG.FTP.09R (Ohms)', 'SG.FTP.10', 'SG.FTP.10R (Ohms)', 'SG.FTP.11', 'SG.FTP.11R (Ohms)', 'SG.FTP.12', 'SG.FTP.12R (Ohms)', 'SG.FTSF.193', 'SG.FTSF.193R (Ohms)', 'SG.B4SW.208T', 'SG.B4SW.208TR (Ohms)', 'SG.FTP.07', 'SG.FTP.07R (Ohms)', 'SG.FTP.08', 'SG.FTP.08R (Ohms)', 'CI.FTSF.198 (mV)'};
            app.Embed5Sensor.Visible = 'off';
            app.Embed5Sensor.Value = 'SG.B4C.59T';
            app.PlaceholderSensor = uilistbox(app.UIFigure);
            app.PlaceholderSensor.Items = {};
            app.PlaceholderSensor.Value = {};
            app.TurbineUnit = uilistbox(app.UIFigure);
            app.TurbineUnit.Items = {'VAWT Shaft Base', 'VAWT Shaft Top'};
            app.TurbineUnit.Visible = 'off';
            app.TurbineUnit.Value = 'VAWT Shaft Base';
            app.TurbineSensor = uilistbox(app.UIFigure);
            app.TurbineSensor.Items = {'Vibration X-Axis (extremum)', 'Vibration X-Axis (average)', 'Vibration Y-Axis (extremum)', 'Vibration Y-Axis (average)', 'Vibration Z-Axis (extremum)', 'Vibration Z-Axis (average)', 'Microphone (extremum)', 'Microphone (average)'};
            app.TurbineSensor.Visible = 'off';
            app.TurbineSensor.Value = 'Vibration X-Axis (extremum)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sensorsDONE

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end